classdef DatasetViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasetViewerUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        BrowseDatasetButton    matlab.ui.control.Button
        EditDatasetButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        LocalDatasetsLabel     matlab.ui.control.Label
    end

    
    properties (Access = private)
        DatasetTable % Description
    end
    
    methods (Access = private)
        
        function populateDatasetTable(app)
            numRows = height(app.DatasetTable);
            if isempty(app.UITable.Data)
                T = app.initializeTable(numRows);
            end

            T.DatasetName = app.DatasetTable.name;
            if hasvars(app.DatasetTable, 'authors')
                T.Authors = app.DatasetTable.authors;
            end

            if hasvars(app.DatasetTable, 'branchName')
                T.BranchName = app.DatasetTable.branchName;
            end

            app.UITable.Data = T;

            function tf = hasvars(tbl, varName)
                tf = any(strcmp(tbl.Properties.VariableNames, varName));
            end
        end
        
        function T = initializeTable(app, numRows);
        % initializeTable - Initialize a data table with the correct
        % columns and datatypes
            T = table();
            T.DatasetName = "";
            T.Authors = {''};
            T.BranchName = "";

            T = repmat(T, numRows, 1);
        end
    end

    methods (Access = private)
        
        function progressBar = wait(app, message, title, varargin)
            if nargin < 3 || isempty(title); title = 'Please Wait...'; end

            progressBar = uiprogressdlg(app.DatasetViewerUIFigure, ...
                "Message", message, ...
                "Title", title, ...
                "Indeterminate", "on", ...
                varargin{:} );
        end

        function alert(app, message, title, varargin)
            if nargin < 3 || isempty(title); title = 'Error'; end
            uialert(app.DatasetViewerUIFigure, message, title, varargin{:})
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import ndi.database.app.dataset_viewer.utility.listLocalDatasets
            import ndi.database.app.dataset_viewer.utility.listRemoteDatasets

            ndi.database.metadata_app.utility.centerFigure(app.DatasetViewerUIFigure)

            app.DatasetTable = listLocalDatasets();           
            %app.DatasetTable = listRemoteDatasets();
            app.populateDatasetTable()
        end

        % Button pushed function: EditDatasetButton
        function EditDatasetButtonPushed(app, event)
            
            % Get selected row
            selectedRowIndex = app.UITable.Selection;
            datasetInfo = table2struct(app.DatasetTable(selectedRowIndex,:));

            if isfolder( datasetInfo.path )
                progressBar = app.wait("Initializing Metadata Editor");
                S = ndi.session.dir(datasetInfo.path);
                ndi.database.metadata_app.Apps.DatasetUploadApp(S)
                delete(progressBar)
            else
                message = sprintf('Folder for the selected dataset is not available: %s', datasetInfo.path);
                title = 'Dataset folder does not exist';
                app.alert(message, title)
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasetViewerUIFigure = uifigure('Visible', 'off');
            app.DatasetViewerUIFigure.Name = 'Dataset Viewer';
            app.GridLayout = uigridlayout(app.DatasetViewerUIFigure);
            app.LocalDatasetsLabel = uilabel(app.GridLayout);
            app.LocalDatasetsLabel.Text = 'Local Datasets';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Dataset Name'; 'Authors'; 'Branch Name'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.SelectionType = 'row';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EditDatasetButton = uibutton(app.GridLayout2, 'push');
            app.EditDatasetButton.ButtonPushedFcn = createCallbackFcn(app, @EditDatasetButtonPushed, true);
            app.EditDatasetButton.Text = 'Edit Dataset';
            app.BrowseDatasetButton = uibutton(app.GridLayout2, 'push');
            app.BrowseDatasetButton.Text = 'Browse Dataset';
            % Show the figure after all components are created
            app.DatasetViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasetViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasetViewerUIFigure)
        end
    end
end