classdef DatasetViewer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasetViewerUIFigure  matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        GridLayout2            matlab.ui.container.GridLayout
        BrowseDatasetButton    matlab.ui.control.Button
        EditDatasetButton      matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        DatasetBrowserLabel    matlab.ui.control.Label
    end

    
    properties (Access = private)
        DatasetTable % Description
    end
    
    methods (Access = private)
        
        function populateDatasetTable(app)
            numRows = height(app.DatasetTable);
            if isempty(app.UITable.Data)
                T = app.initializeTable(numRows);
            end
            T.DatasetName = app.DatasetTable.name;
            T.Authors = app.DatasetTable.authors;
            T.BranchName = app.DatasetTable.branchName;

            app.UITable.Data = T;
        end
        
        function T = initializeTable(app, numRows);
        % initializeTable - Initialize a data table with the correct
        % columns and datatypes
            T = table();
            T.DatasetName = "";
            T.Authors = {''};
            T.BranchName = "";

            T = repmat(T, numRows, 1);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import ndi.database.dataset_viewer.utility.listLocalDatasets
            import ndi.database.dataset_viewer.utility.listRemoteDatasets

            
            %app.DatasetTable = listLocalDatasets();           
            app.DatasetTable = listRemoteDatasets();

            app.populateDatasetTable()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DatasetViewerUIFigure = uifigure('Visible', 'off');
            app.DatasetViewerUIFigure.Name = 'Dataset Viewer';
            app.GridLayout = uigridlayout(app.DatasetViewerUIFigure);
            app.DatasetBrowserLabel = uilabel(app.GridLayout);
            app.DatasetBrowserLabel.Text = 'Dataset Browser';
            app.UITable = uitable(app.GridLayout);
            app.UITable.ColumnName = {'Dataset Name'; 'Authors'; 'Branch Name'; 'Column 4'};
            app.UITable.RowName = {};
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.EditDatasetButton = uibutton(app.GridLayout2, 'push');
            app.EditDatasetButton.Text = 'Edit Dataset';
            app.BrowseDatasetButton = uibutton(app.GridLayout2, 'push');
            app.BrowseDatasetButton.Text = 'Browse Dataset';
            % Show the figure after all components are created
            app.DatasetViewerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetViewer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasetViewerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasetViewerUIFigure)
        end
    end
end