classdef AffiliationForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        Panel                           matlab.ui.container.Panel
        AffiliationNameEditField        matlab.ui.control.EditField
        RORidEditField                  matlab.ui.control.EditField
        SearchRoridButton               matlab.ui.control.Button
        RORidEditFieldLabel             matlab.ui.control.Label
        OrganizationNameEditFieldLabel  matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        CreateButton                    matlab.ui.control.Button
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
    
        function setAffiliationDetails(app, S)
            app.AffiliationNameEditField.Value = S.AffiliationName;
            app.RORidEditField.Value = S.RORId;
        end

        function S = getAffiliationDetails(app)
            S = struct;
            S.AffiliationName = app.AffiliationNameEditField.Value;
            S.RORId = app.RORidEditField.Value;
        end

        function reset(app)
            app.AffiliationNameEditField.Value = '';
            app.RORidEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end

        function setName(app, name)
            app.AffiliationNameEditField.Value = name;
        end

        % Check if any RORID belongs to some institution perfectly matching the
        % given name.
        function checkRoridMatch(app, fullName)
            try
                rorid = ndi.database.metadata_app.fun.getRorId(fullName);
                if ~isempty(rorid)
                    orcidLink = sprintf("https://orcid.org/%s", rorid);
                    msg = sprintf('The following ORCID <a href="%s">%s</a> was found matching the given author name. Please check if the information matches the intended author', orcidLink, rorid);
                    answer = uiconfirm(app.NDIDatasetWizardUIFigure, msg, "Review ORCID", "Options", {'Confirm', 'Reject'}, 'CancelOption', 'Reject', 'Interpreter', 'html');
                    if strcmp( answer, 'Confirm' )
                        app.DigitalIdentifierORCIDEditField.Value = orcidLink;

                        authorIndex = app.getSelectedAuthorIndex();
                        app.AuthorData.updateProperty('DigitalIdentifier', orcidLink, authorIndex)
                        app.saveDatasetInformation('Author2')
                    end
                end 
            catch
                % Pass for now
            end
        end

    end

    methods (Access = private)
        function af = testCreateAffiliation(app, AffiliationName, RORId, StartDate, EndDate)
            try 
                ror = openminds.core.RORID('identifier',RORId);
                org = openminds.core.Organization('digitalIdentifier',ror,...
                    'fullName',AffiliationName);
                af = openminds.core.Affiliation('memberOf', org);
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AffiliationNameEditField
        function AffiliationNameChangedFcn(app, event)
            value = app.AffiliationNameEditField.Value;
            app.checkRoridMatch(value)
        end

        % Value changed function: RORidEditField
        function RORidValueChanged(app, event)
            value = app.RORidEditField.Value;
            [name, rorID] = ndi.database.metadata_app.fun.checkValidRORID(value);
            if (isempty(rorID))
                errMessage = sprintf('The entered value is not a valid RORID. For examples of valid RORID, please see this <a href=https://ror.readme.io/docs/ror-identifier-pattern>link</a>');
                uialert(app.UIFigure, errMessage, 'Invalid RORID', 'Interpreter', 'html')
            else
                app.AffiliationNameEditField.Value = name;
            end
        end

        % Callback function
        function StartdateEditFieldValueChanged(app, event)
            value = app.StartdateEditField.Value;
            % Check that date is formatted as 'YYYY-MM-DD'.
            try
                ndi.database.metadata_app.fun.mustBeValidDate(value)
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end

        % Callback function
        function enddateEditFieldValueChanged(app, event)
            value = app.enddateEditField.Value;
            % Check that date is formatted as 'YYYY-MM-DD'.
            try
                ndi.database.metadata_app.fun.mustBeValidDate(value)
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.RORidEditField.Value)
                uialert(app.UIFigure, 'Please fill in the ROR id', 'Empty ROR id');
            else
                app.FinishState = "Save";
                app.UIFigureCloseRequest();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new affiliation?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: SearchRoridButton
        function SearchRoridButtonPushed(app, event)
            fullName = app.AffiliationNameEditField.Value;
            apiQueryUrl = ndi.database.metadata_app.fun.getRorIdSearchUrl(fullName);
            web(apiQueryUrl)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Edit Author Details';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.OrganizationNameEditFieldLabel = uilabel(app.Panel);
            app.OrganizationNameEditFieldLabel.Text = 'Organization Name';
            app.RORidEditFieldLabel = uilabel(app.Panel);
            app.RORidEditFieldLabel.Text = 'ROR id';
            app.SearchRoridButton = uibutton(app.Panel, 'push');
            app.SearchRoridButton.ButtonPushedFcn = createCallbackFcn(app, @SearchRoridButtonPushed, true);
            app.SearchRoridButton.Icon = fullfile(pathToMLAPP, '+icons', 'search.png');
            app.SearchRoridButton.Text = '';
            app.RORidEditField = uieditfield(app.Panel, 'text');
            app.RORidEditField.ValueChangedFcn = createCallbackFcn(app, @RORidValueChanged, true);
            app.AffiliationNameEditField = uieditfield(app.Panel, 'text');
            app.AffiliationNameEditField.ValueChangedFcn = createCallbackFcn(app, @AffiliationNameChangedFcn, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AffiliationForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end