classdef ElectrodeArrayForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditElectrodeArrayInformationUIFigure  matlab.ui.Figure
        NoofElectrodesEditFieldLabel_2  matlab.ui.control.Label
        CompleteCheckBox                matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ManufacturerEditField           matlab.ui.control.EditField
        ManufaturerEditFieldLabel       matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        ElectrodeIdentifierEditField    matlab.ui.control.EditField
        ElectrodeIdentifierEditFieldLabel  matlab.ui.control.Label
        NoofElectrodesEditField         matlab.ui.control.EditField
        NoofElectrodesEditFieldLabel    matlab.ui.control.Label
        DeviceTypeEditField             matlab.ui.control.EditField
        DeviceTypeEditFieldLabel        matlab.ui.control.Label
        ELECTRODEARRAYLabel             matlab.ui.control.Label
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.EditElectrodeArrayInformationUIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.EditElectrodeArrayInformationUIFigure.Visible;
        end
    end

    methods (Access = public)
    
        function setProbeDetails(app, electrodeArray)
            app.NameEditField.Value = electrodeArray.Name;
            app.DeviceTypeEditField.Value = electrodeArray.DeviceType;
            app.DescriptionEditField.Value = electrodeArray.Description;
            app.ElectrodeIdentifierEditField.Value = electrodeArray.ElectrodeIdentifier;
            app.DigitalIdentifierEditField.Value = electrodeArray.DigitalIdentifier;
            app.CompleteCheckBox.Value = electrodeArray.Complete;
            app.NoofElectrodesEditField.Value = electrodeArray.NoofElectrodes;
            app.ManufacturerEditField.Value = electrodeArray.Manufacturer;
        end

        function electrodeArray = getProbeDetails(app)
            electrodeArray = ndi.database.metadata_app.class.ElectrodeArray();
            electrodeArray.updateProperty("Name", app.NameEditField.Value);
            electrodeArray.updateProperty("DeviceType", app.DeviceTypeEditField.Value);
            electrodeArray.updateProperty("Description", app.DescriptionEditField.Value);
            electrodeArray.updateProperty("ElectrodeIdentifier", app.ElectrodeIdentifierEditField.Value);
            electrodeArray.updateProperty("DigitalIdentifier", app.DigitalIdentifierEditField.Value);
            electrodeArray.updateProperty("Complete", app.CompleteCheckBox.Value);
            electrodeArray.updateProperty("NoofElectrodes", app.NoofElectrodesEditField.Value);
            electrodeArray.updateProperty("Manufacturer", app.ManufacturerEditField.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DeviceTypeEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.ElectrodeIdentifierEditField.Value = '';
            app.DigitalIdentifierEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.NoofElectrodesEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.EditElectrodeArrayInformationUIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.FinishState = "Save";
            app.EditElectrodeArrayInformationUIFigureCloseRequest()
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.EditElectrodeArrayInformationUIFigureCloseRequest()
        end

        % Close request function: EditElectrodeArrayInformationUIFigure
        function EditElectrodeArrayInformationUIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.EditElectrodeArrayInformationUIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.EditElectrodeArrayInformationUIFigure)
            end
        end

        % Value changed function: CompleteCheckBox
        function ValueChanged(app, event)
            value = app.CompleteCheckBox.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditElectrodeArrayInformationUIFigure = uifigure('Visible', 'off');
            app.EditElectrodeArrayInformationUIFigure.Name = 'Edit Electrode Array Information';
            app.EditElectrodeArrayInformationUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditElectrodeArrayInformationUIFigureCloseRequest, true);
            app.ELECTRODEARRAYLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.ELECTRODEARRAYLabel.Text = 'ELECTRODE ARRAY';
            app.DeviceTypeEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.DeviceTypeEditFieldLabel.Text = 'Device Type';
            app.DeviceTypeEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.NoofElectrodesEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.NoofElectrodesEditFieldLabel.Text = 'No. of Electrodes';
            app.NoofElectrodesEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.ElectrodeIdentifierEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.ElectrodeIdentifierEditFieldLabel.Text = 'Electrode Identifier';
            app.ElectrodeIdentifierEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.NameEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.DescriptionEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.ManufaturerEditFieldLabel = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.ManufaturerEditFieldLabel.Text = 'Manufaturer';
            app.ManufacturerEditField = uieditfield(app.EditElectrodeArrayInformationUIFigure, 'text');
            app.SaveButton = uibutton(app.EditElectrodeArrayInformationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.EditElectrodeArrayInformationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CompleteCheckBox = uicheckbox(app.EditElectrodeArrayInformationUIFigure);
            app.CompleteCheckBox.ValueChangedFcn = createCallbackFcn(app, @ValueChanged, true);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel_2 = uilabel(app.EditElectrodeArrayInformationUIFigure);
            app.NoofElectrodesEditFieldLabel_2.Text = 'Complete';
            % Show the figure after all components are created
            app.EditElectrodeArrayInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectrodeArrayForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditElectrodeArrayInformationUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditElectrodeArrayInformationUIFigure)
        end
    end
end