classdef ElectrodeForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EditElectrodeInformationUIFigure  matlab.ui.Figure
        UnitListBox                     matlab.ui.control.ListBox
        DigitalIdentifierDropDownLabel  matlab.ui.control.Label
        UnitListBoxLabel                matlab.ui.control.Label
        NoofElectrodesEditFieldLabel    matlab.ui.control.Label
        CompleteCheckBox                matlab.ui.control.CheckBox
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerLabel               matlab.ui.control.Label
        DigitalIdentifierDropDown       matlab.ui.control.DropDown
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        DescriptionEditField            matlab.ui.control.EditField
        DescriptionEditFieldLabel       matlab.ui.control.Label
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        IntrinsicResistanceEditField    matlab.ui.control.EditField
        IntrinsicResistanceEditFieldLabel  matlab.ui.control.Label
        ELECTRODELabel                  matlab.ui.control.Label
    end

        
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
        Electrode
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        % A struct for storing input form apps, like the Author form. Used
        % to keep external forms/figures in memory, but hidden
        UIForm (1,1) struct
        Manufacturer
    end

    methods 
        function set.Visible(app, value)
            app.EditElectrodeInformationUIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.EditElectrodeInformationUIFigure.Visible;
        end
    end

    methods (Access = private)
        % Open (external app) form where user can enter affiliation details
        % function openManufacturerForm(app, manufacturerName)
        % %openManufacturerForm Open Affiliation form where user can enter Affiliation details
        %     if ~isfield(app.UIForm, 'Manufacturer')
        %         app.UIForm.Manufacturer = ndi.database.metadata_app.Apps.AffiliationForm(); % Create the form
        %     else
        %         app.UIForm.Manufacturer.Visible = 'on'; % Make the form visible
        %     end
        % 
        %     if nargin > 1 && ~isempty(manufacturerName)
        %         app.UIForm.Manufacturer.setName(manufacturerName);
        %     end
        % 
        %     app.UIForm.Manufacturer.waitfor(); % Wait for user to proceed
        % 
        %     % Get user-inputs from form
        %     S = app.UIForm.Manufacturer.getAffiliationDetails();
        % 
        %     % Update data in table if user pressed save.
        %     mode = app.UIForm.Manufacturer.FinishState;
        %     if mode == "Save"
        %         app.ManufacturerEditField.Value = S.AffiliationName;
        %     end
        %     app.Manufacturer = S;
        % 
        %     app.UIForm.Manufacturer.reset()
        %     app.UIForm.Manufacturer.Visible = 'off'; % Hide the form (for later reuse)
        % end

        % Open (external app) form where user can enter manufacturer details
        function S = openOrganizationForm(app, organizationInfo, organizationIndex)
        %openOrganizationForm Open Organization form where user can enter organization details
            progressdlg = uiprogressdlg(app.EditElectrodeInformationUIFigure, ...
                    "Indeterminate", "on", "Message", "Opening Organization Form, please wait...", ...
                    "Title", "Please Wait");

            if ~isfield(app.UIForm, 'Organization')
                app.UIForm.Organization = ndi.database.metadata_app.Apps.OrganizationForm(); % Create the form
            else
                app.UIForm.Organization.Visible = 'on'; % Make the form visible
            end
            progressdlg.Message = "Please fill in the organization information";

            if nargin > 1 && ~isempty(organizationInfo)
                % Update form information if we are editing an organization
                app.UIForm.Organization.setOrganizationDetails(organizationInfo);
            end
         
            app.UIForm.Organization.waitfor(); % Wait for user to proceed
            
            % Get user-inputs from form
            S = app.UIForm.Organization.getOrganizationDetails();

            progressdlg.Indeterminate = "off";
            progressdlg.Message = "Saving manufacturer...";
            pause(1.5)
            delete(progressdlg)
            
            % Update data in table if user pressed save.
            mode = app.UIForm.Organization.FinishState;
            if mode == "Save"
                if nargin > 2 && ~isempty(organizationIndex)
                    app.insertOrganization(S, organizationIndex)
                else
                    app.insertOrganization(S);
                end
                app.ManufacturerEditField.Value = app.Manufacturer.fullName;
            else
                S = struct.empty;
                app.ManufacturerEditField.Value = '';
            end


            app.UIForm.Organization.reset()
            app.UIForm.Organization.Visible = 'off'; % Hide the form (for later reuse)

            if ~nargout
                clear S
            end
        end

        function insertOrganization(app, S, insertIndex)

            if nargin < 3 || isempty(insertIndex)
                insertIndex = numel(app.Manufacturer) + 1;
            end

            if isempty(app.Manufacturer)
                app.Manufacturer = S;
            else
                app.Manufacturer(insertIndex) = S;
            end
            
            % Update organization dropdown
            organizationNames = {app.Manufacturer.fullName};
            % app.OrganizationDropDown.Items = organizationNames;
            app.saveOrganizationInstances()
        end

        function loadOrganizations(app)
            import ndi.database.metadata_app.fun.loadUserInstances
            app.Organizations = loadUserInstances('manufacturer_organization');
        end

        function saveOrganizationInstances(app)
            import ndi.database.metadata_app.fun.saveUserInstances
            organizationInstances = app.Manufacturer;
            saveUserInstances('manufacturer_organization', organizationInstances)
        end

        function selected = digitalIdentifierSelected(app)
            if strcmp(app.DigitalIdentifierDropDown.Value, "Select type")
                selected = 0;
            else
                selected = 1;
            end
        end

        function entered = intrinsicResistanceEntered(app)
            if isempty(app.IntrinsicResistanceEditField.Value)
                entered = 0;
            else
                entered = 1;
            end
        end
    end

    methods (Access = public)
    
        function setProbeDetails(app, electrode)
            app.Electrode = electrode;
            app.NameEditField.Value = electrode.Name;
            app.DescriptionEditField.Value = electrode.Description;
            [type, value] = electrode.getDigitalIdentifier();
            app.DigitalIdentifierDropDown.Value = type;
            app.DigitalIdentifierEditField.Value = value;
            app.Manufacturer = electrode.Manufacturer;
            app.ManufacturerEditField.Value = electrode.getManufacturerName();
            app.CompleteCheckBox.Value = electrode.Complete;
            app.IntrinsicResistanceEditField.Value = electrode.IntrinsicResistance;
            app.UnitListBox.Value = electrode.IntrinsicResistanceUnit;
        end

        function electrode = getProbeDetails(app)
            if isempty(app.Electrode)
                electrode = ndi.database.metadata_app.class.Electrode();
            else
                electrode = app.Electrode;
            end
            electrode.updateProperty("Name", app.NameEditField.Value);
            electrode.updateProperty("Description", app.DescriptionEditField.Value);
            digitalIdentifier = struct('Type', app.DigitalIdentifierDropDown.Value, 'Value', app.DigitalIdentifierEditField.Value);
            electrode.updateProperty("DigitalIdentifier", digitalIdentifier);
            electrode.updateProperty("Manufacturer", app.Manufacturer);
            electrode.updateProperty("Complete", app.CompleteCheckBox.Value);
            electrode.updateProperty("IntrinsicResistance", app.IntrinsicResistanceEditField.Value);
            electrode.updateProperty("IntrinsicResistanceUnit", app.UnitListBox.Value);
        end

        function reset(app)
            app.NameEditField.Value = '';
            app.DescriptionEditField.Value = '';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.DigitalIdentifierEditField.Value = '';
            app.ManufacturerEditField.Value = '';
            app.CompleteCheckBox.Value = 0;
            app.IntrinsicResistanceEditField.Value = '';
            app.UnitListBox.Value = app.UnitListBox.Items{1};
            app.FinishState = "";
        end

      
        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.EditElectrodeInformationUIFigure)
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function StartUp(app)
            ndi.database.metadata_app.fun.loadInstancesToListBox(app.UnitListBox, 'UnitOfMeasurement', {'ohm'});
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            electrode = app.getProbeDetails();
            if ~electrode.intrinsicResistanceCheck()
                uialert(app.EditElectrodeInformationUIFigure, 'Please select a unit for intrinsic resistance or delete the value enetered in the instrinsic resistance field.', 'Empty Intrinsic Resistance Unit');
            else
                app.FinishState = "Save";
                app.EditElectrodeInformationUIFigureCloseRequest()
            end
            % electrode.makeOpenMindsObj()
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.EditElectrodeInformationUIFigureCloseRequest()
        end

        % Close request function: EditElectrodeInformationUIFigure
        function EditElectrodeInformationUIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.EditElectrodeInformationUIFigure, 'Do you want to save the new electrode array?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.EditElectrodeInformationUIFigure)
            end
        end

        % Value changed function: ManufacturerEditField
        function ManufacturerValueChanged(app, event)
            value = app.ManufacturerEditField.Value;
            S.fullName = value;
            app.openOrganizationForm(S);
        end

        % Value changed function: UnitListBox
        function UnitListBoxValueChanged(app, event)
            value = app.UnitListBox.Value;
            if ~(app.intrinsicResistanceEntered())
                app.UnitListBox.Value = app.UnitListBox.Items{1};
                uialert(app.EditElectrodeInformationUIFigure, 'Please enter value for intrinsic resistance', 'Empty intrinsic resistance');
            end
            
        end

        % Value changed function: DigitalIdentifierEditField
        function DigitalIdentifierValueChanged(app, event)
            value = app.DigitalIdentifierEditField.Value;
            if ~(app.digitalIdentifierSelected())
                app.DigitalIdentifierEditField.Value = '';
                uialert(app.EditElectrodeInformationUIFigure, 'Please select the digital identifier type', 'Empty Digital Identifier Type');
            end
        end

        % Value changed function: DigitalIdentifierDropDown
        function DigitalIdentifierTypeChanged(app, event)
            value = app.DigitalIdentifierDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EditElectrodeInformationUIFigure = uifigure('Visible', 'off');
            app.EditElectrodeInformationUIFigure.Name = 'Edit Electrode Information';
            app.EditElectrodeInformationUIFigure.CloseRequestFcn = createCallbackFcn(app, @EditElectrodeInformationUIFigureCloseRequest, true);
            app.ELECTRODELabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.ELECTRODELabel.Text = 'ELECTRODE';
            app.IntrinsicResistanceEditFieldLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.IntrinsicResistanceEditFieldLabel.Text = 'Intrinsic Resistance';
            app.IntrinsicResistanceEditField = uieditfield(app.EditElectrodeInformationUIFigure, 'text');
            app.IntrinsicResistanceEditField.Tooltip = {'Optional'};
            app.NameEditFieldLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.EditElectrodeInformationUIFigure, 'text');
            app.NameEditField.Editable = 'off';
            app.DescriptionEditFieldLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.DescriptionEditFieldLabel.Text = 'Description';
            app.DescriptionEditField = uieditfield(app.EditElectrodeInformationUIFigure, 'text');
            app.DescriptionEditField.Placeholder = 'Catalog No.';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.EditElectrodeInformationUIFigure, 'text');
            app.DigitalIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierValueChanged, true);
            app.DigitalIdentifierEditField.Placeholder = 'DOI/RRID';
            app.DigitalIdentifierDropDown = uidropdown(app.EditElectrodeInformationUIFigure);
            app.DigitalIdentifierDropDown.Items = {'Select type', 'ROI', 'RRID'};
            app.DigitalIdentifierDropDown.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierTypeChanged, true);
            app.DigitalIdentifierDropDown.Placeholder = 'Select type';
            app.DigitalIdentifierDropDown.Value = 'Select type';
            app.ManufacturerLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.ManufacturerLabel.Text = 'Manufacturer';
            app.ManufacturerEditField = uieditfield(app.EditElectrodeInformationUIFigure, 'text');
            app.ManufacturerEditField.ValueChangedFcn = createCallbackFcn(app, @ManufacturerValueChanged, true);
            app.SaveButton = uibutton(app.EditElectrodeInformationUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.EditElectrodeInformationUIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CompleteCheckBox = uicheckbox(app.EditElectrodeInformationUIFigure);
            app.CompleteCheckBox.Text = '';
            app.NoofElectrodesEditFieldLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.NoofElectrodesEditFieldLabel.Text = 'Complete';
            app.UnitListBoxLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.UnitListBoxLabel.Text = 'Unit';
            app.DigitalIdentifierDropDownLabel = uilabel(app.EditElectrodeInformationUIFigure);
            app.DigitalIdentifierDropDownLabel.Text = 'Digital Identifier';
            app.UnitListBox = uilistbox(app.EditElectrodeInformationUIFigure);
            app.UnitListBox.Items = {'load'};
            app.UnitListBox.ValueChangedFcn = createCallbackFcn(app, @UnitListBoxValueChanged, true);
            app.UnitListBox.Value = 'load';
            % Show the figure after all components are created
            app.EditElectrodeInformationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ElectrodeForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EditElectrodeInformationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @StartUp)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EditElectrodeInformationUIFigure)
        end
    end
end