classdef FundingForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        EnterFundingDetailsLabel   matlab.ui.control.Label
        Panel                      matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        AwardNumberEditFieldLabel  matlab.ui.control.Label
        FunderDropDownLabel        matlab.ui.control.Label
        AwardTitleEditField        matlab.ui.control.EditField
        AwardNumberEditField       matlab.ui.control.EditField
        AddFunderButton            matlab.ui.control.Button
        FunderDropDown             matlab.ui.control.DropDown
        AwardTitleEditFieldLabel   matlab.ui.control.Label
        GridLayout2                matlab.ui.container.GridLayout
        CancelButton               matlab.ui.control.Button
        CreateButton               matlab.ui.control.Button
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        UIForm
        FunderOrganizations (1,:) struct % Struct with fields: fullName, digitalIdentifier
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    properties (Constant, Access = private)
        FunderPlaceHolder = "<Select existing or create funder>"
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
    
        function setFunderDetails(app, S)
            if isfield(S, 'funder')
                app.FunderDropDown.Value = S.funder;
            end
            if isfield(S, 'awardTitle')
                app.AwardTitleEditField.Value = S.awardTitle;
            end
                        
            if isfield(S, 'awardNumber')
                app.AwardNumberEditField.Value = S.awardNumber;
            end
        end

        function S = getFunderDetails(app)
            S = struct;
            S.funder = app.FunderDropDown.Value;
            S.awardTitle = app.AwardTitleEditField.Value;
            S.awardNumber = app.AwardNumberEditField.Value;

        end

        function reset(app)
            % Reset the funder dropdown selection
            app.FunderDropDown.Value = app.FunderDropDown.Items{1};
            app.AwardNumberEditField.Value = '';
            app.AwardTitleEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end

        function setName(app, name)
            app.FunderDropDown.Value = name;
        end

    end

    methods (Access = private)
        function funder = testCreateFunder(app)
            try 
                % todo
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end

        function loadOrganizations(app)
            import ndi.database.metadata_app.fun.loadUserInstances
            app.FunderOrganizations = loadUserInstances('funder_organization');
        end

        function saveOrganizationInstances(app)
            import ndi.database.metadata_app.fun.saveUserInstances
            organizationInstances = app.FunderOrganizations;
            saveUserInstances('funder_organization', organizationInstances)
        end

        function insertOrganization(app, S)
            if isempty(app.FunderOrganizations)
                app.FunderOrganizations = S;
            else
                isNew = app.assertNewOrganization(S);
                if ~isNew; return; end
                app.FunderOrganizations(end+1) = S;
            end

            app.saveOrganizationInstances()
            app.updateFunderDropdown()
        end

        function isNew = assertNewOrganization(app, S)
        % assertNewOrganization - Check whether an organization already
        % exists in the list of organizations
            
            allOrganizationIds = { app.FunderOrganizations.digitalIdentifier };
            
            isIdMatch = strcmp(allOrganizationIds, S.digitalIdentifier);
            if any(isIdMatch)
                isNew = false;
                existingOrganizationName = app.FunderOrganizations(isIdMatch).fullName;
                message = sprintf( 'An organization with the provided digital identifier ("%s") already exists in the list.', existingOrganizationName);
                uialert(app.UIFigure, message, 'Organization already exists', 'Icon', 'info')
            else
                allOrganizationNames = {app.FunderOrganizations.fullName};
                if any(strcmp(allOrganizationNames, S.fullName))
                    answer = uiconfirm(app.UIFigure, ...
                        "An organization with the given name but a different digital identifier already exists in the list. This seems like a mistake. Do you want to proceed anyway?", ...
                        "Ducplicate organization", ...
                        "Options", {'Yes', 'Cancel'});

                    if strcmp(answer, 'Yes')
                        isNew = true;
                    else
                        isNew = false;
                    end
                else
                    isNew = true;
                end
            end
        end

        % Open (external app) form where user can enter author details
        function S = openOrganizationForm(app)
        %openOrganizationForm Open Organization form where user can enter organization details
            progressDialog = uiprogressdlg(app.UIFigure, ...
                'Message', 'Opening form for entering funder details', ...
                'Title', 'Please wait...', 'Indeterminate', "on");
            if ~isfield(app.UIForm, 'Organization')
                app.UIForm.Organization = ndi.database.metadata_app.Apps.OrganizationForm(); % Create the form
            else
                app.UIForm.Organization.Visible = 'on'; % Make the form visible
            end
            app.UIForm.Organization.UIFigure.Visible = 'off';
            app.UIForm.Organization.UIFigure.WindowStyle = "modal";
            drawnow
            delete(progressDialog)
            app.UIForm.Organization.UIFigure.Visible = 'on';
            app.UIForm.Organization.UIFigure.Position = app.UIFigure.Position;
            app.UIForm.Organization.waitfor(); % Wait for user to proceed

            % Get user-inputs and exit mode from form dialog
            S = app.UIForm.Organization.getOrganizationDetails();           
            mode = app.UIForm.Organization.FinishState;
            
            % Reset the organization form dialog window
            app.UIForm.Organization.reset()
            app.UIForm.Organization.Visible = 'off'; % Hide the form (for later reuse)
            
            % Update data in table if user pressed save.
            if mode == "Save"
                app.insertOrganization(S);
            else
                S = struct.empty;
            end
            
            if ~nargout
                clear S
            end
        end
        
        function updateFunderDropdown(app)
            if ~isempty(app.FunderOrganizations)
                options = {app.FunderOrganizations.fullName};
                options = [app.FunderPlaceHolder, options];
                app.FunderDropDown.Items = options;
            else
                app.FunderDropDown.Placeholder = "Add new funder →";
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.loadOrganizations()

            % Load funding organizations
            app.updateFunderDropdown()
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if strcmp(app.FunderDropDown.Value, app.FunderPlaceHolder) 
                uialert(app.UIFigure, 'Please add a funder', 'Funder is requireds');
            else
                app.FinishState = "Save";
                app.UIFigureCloseRequest();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new funding information?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: AddFunderButton
        function AddFunderButtonPushed(app, event)
            S = openOrganizationForm(app);
            if ~isempty(S)
                app.updateFunderDropdown()
                app.FunderDropDown.Value = S.fullName;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Create Funding';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.AwardTitleEditFieldLabel = uilabel(app.GridLayout3);
            app.AwardTitleEditFieldLabel.Text = 'Award Title';
            app.FunderDropDown = uidropdown(app.GridLayout3);
            app.FunderDropDown.Items = {};
            app.FunderDropDown.Value = {};
            app.AddFunderButton = uibutton(app.GridLayout3, 'push');
            app.AddFunderButton.ButtonPushedFcn = createCallbackFcn(app, @AddFunderButtonPushed, true);
            app.AddFunderButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'plus.png');
            app.AddFunderButton.Tooltip = {'Add New Funder'};
            app.AddFunderButton.Text = '';
            app.AwardNumberEditField = uieditfield(app.GridLayout3, 'text');
            app.AwardTitleEditField = uieditfield(app.GridLayout3, 'text');
            app.FunderDropDownLabel = uilabel(app.GridLayout3);
            app.FunderDropDownLabel.Tooltip = {'Enter a funder'};
            app.FunderDropDownLabel.Text = 'Funder';
            app.AwardNumberEditFieldLabel = uilabel(app.GridLayout3);
            app.AwardNumberEditFieldLabel.Text = 'Award Number';
            app.EnterFundingDetailsLabel = uilabel(app.GridLayout);
            app.EnterFundingDetailsLabel.Text = 'Enter Funding Details';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FundingForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end