classdef LoginForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        LoginTab                  matlab.ui.container.Tab
        GridLayout2               matlab.ui.container.GridLayout
        LoginButton               matlab.ui.control.Button
        PasswordEditField         matlab.ui.control.EditField
        PasswordEditFieldLabel    matlab.ui.control.Label
        EmailEditFieldLabel       matlab.ui.control.Label
        EmailEditField            matlab.ui.control.EditField
        LoginLabel                matlab.ui.control.Label
        DatasetTab                matlab.ui.container.Tab
        GridLayout                matlab.ui.container.GridLayout
        AbstractTextArea          matlab.ui.control.TextArea
        AbstractTextAreaLabel     matlab.ui.control.Label
        AuthorsEditField          matlab.ui.control.EditField
        AuthorsLabel              matlab.ui.control.Label
        BranchNameEditField       matlab.ui.control.EditField
        BranchNameEditFieldLabel  matlab.ui.control.Label
        NameEditField             matlab.ui.control.EditField
        NameEditFieldLabel        matlab.ui.control.Label
        SelectButton              matlab.ui.control.Button
        DatasetsListBox           matlab.ui.control.ListBox
        EmailEditFieldLabel_2     matlab.ui.control.Label
        DatasetsLabel             matlab.ui.control.Label
    end

    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user login or cancels
        FinishState (1,1) string % "Save" or "Cancel"
        LoginInformation
        DatasetData
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)

    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
    
        function setLoginDetails(app, loginInformation)
            if isfield(loginInformation, "email")
                app.EmailEditField.Value = loginInformation.email;
            end
            if isfield(loginInformation, "password")
                app.PasswordEditField.Value = loginInformation.password;
            end
        end

        function loginInformation = getLoginDetails(app)
            loginInformation.email = app.EmailEditField.Value;
            loginInformation.password = app.PasswordEditField.Value;
            app.LoginInformation = loginInformation;
        end

        function valid = checkInput(app)
            app.getLoginDetails();
            valid = 1;
            if (isempty(app.LoginInformation.email) || isempty(app.LoginInformation.password))
                valid = 0;
                errMessage = sprintf('Please fill in your Email and password.');
                uialert(app.UIFigure, errMessage, 'Empty Email or Password');
            end
        end

        function authentication(app)
            try
                progressdlg = uiprogressdlg(app.UIFigure, ...
                    "Indeterminate", "on", "Message", "Logging in...", ...
                    "Title", "Please Wait");
                [status, auth_token, organization_id] = ndi.cloud.api.auth.login(app.LoginInformation.email, app.LoginInformation.password);
                
                if ~status
                    progressdlg.Indeterminate = "off";
                    progressdlg.Message = "Logged In";
                    delete(progressdlg)
                    app.LoginInformation.auth_token = auth_token;
                    app.LoginInformation.organization_id = organization_id;
                    app.getDatasets(auth_token, organization_id);
                else
                    progressdlg.Indeterminate = "off";
                    delete(progressdlg)
                    errMessage = sprintf('Invalid Email or passwrod.');
                    uialert(app.UIFigure, errMessage, 'Please enter valid Email and password');
                end 
            catch ME
               disp(ME.message); 
            end
        end

        function getDatasets(app, auth_token,organization_id)
            try
                progressdlg = uiprogressdlg(app.UIFigure, ...
                    "Indeterminate", "on", "Message", "Fetching organization information...", ...
                    "Title", "Please Wait");
                [status, ~, datasets] = ndi.cloud.api.datasets.get_organizations(organization_id, auth_token);
                if status
                    error("Unknown error occured. Please check the log.");
                end
                progressdlg.Indeterminate = "off";
                progressdlg.Message = "Organization Found";
                delete(progressdlg)
                if numel(datasets) >= 1
                    msg = sprintf('Existing dataset was found in your organization. Do you wish to update existing dataset or create a new one?');
                    answer = uiconfirm(app.UIFigure, msg, "Create new Dataset", "Options", {'Create', 'Update'}, 'CancelOption', 'Create', 'Interpreter', 'html');
                    if strcmp(answer, 'Create')
                        app.LoginInformation.dataset_id = 0;
                        app.FinishState = "Save";
                        app.UIFigureCloseRequest();
                        %% TODO: 
                        % make a function that put the metadata in correct
                        % structure and upload. 
                        
                    else
                        app.TabGroup.SelectedTab = app.DatasetTab;
                        app.DatasetData = datasets;
                        app.generateDatasetListbox(datasets);
                    end
                else
                    app.LoginInformation.dataset_id = 0;
                    app.FinishState = "Save";
                    app.UIFigureCloseRequest();
                end
            catch ME
               disp(ME.message);
            end
        end

        function generateDatasetListbox(app, datasets)
            names = {};
            dataset_ids = {};
            for i = 1:numel(datasets)
                names{i} = datasets{i}.name;
                dataset_ids{i} = datasets{i}.id;
            end
            app.DatasetsListBox.Items = names;
            app.DatasetsListBox.ItemsData = dataset_ids;
            app.DatasetsListBox.Value = app.DatasetsListBox.ItemsData{1};
            app.fillDatasetInputFieldsFromIndex(1);
        end

        % Get the index for the selected dataset in the dataset listbox
        function datasetIndex = getSelectedDatasetIndex(app)
            isSelected = strcmp(app.DatasetsListBox.ItemsData, app.DatasetsListBox.Value);
            datasetIndex = find(isSelected);
        end

        function authorNames = getFormatAuthors(app, author)
            if isempty(author)
                authorNames = "";
                return;
            end
            authorNames = sprintf("%s %s", author(1).firstName, author(1).lastName);
            for i = 2:numel(author)
                authorNames = authorNames + sprintf(", %s %s", author(i).firstName, author(i).lastName);
            end
        end

        function fillDatasetInputFieldsFromIndex(app, datasetIndex)
            % Note: Not all fields are handled yet
            dataset = app.DatasetData{datasetIndex};
            if isfield(dataset, "name")
                app.NameEditField.Value = dataset.name;
            else
                app.NameEditField.Value = "";
            end
            if isfield(dataset, "branchName")
                app.BranchNameEditField.Value = dataset.branchName;
            else
                app.BranchNameEditField.Value = "";
            end
            if isfield(dataset, "contributors")
                app.AuthorsEditField.Value = app.getFormatAuthors(dataset.contributors);
            else
                app.AuthorsEditField.Value = "";
            end
            if isfield(dataset, "abstract")
                app.AbstractTextArea.Value = dataset.abstract;
            else
                app.AbstractTextArea.Value = "";
            end
        end

        function reset(app)
            app.EmailEditField.Value = '';
            app.PasswordEditField.Value = '';
            app.FinishState = "";
        end

      
        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoginButton
        function loginButtonPushed(app, event)
            if ~app.checkInput()
                return;
            end
            app.authentication();
        end

        % Value changed function: DatasetsListBox
        function datasetsListBoxValueChanged(app, event)
            datasetIndex = app.getSelectedDatasetIndex();
            app.fillDatasetInputFieldsFromIndex(datasetIndex);
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.LoginInformation.dataset_id = app.DatasetsListBox.Value;
            app.FinishState = "Save";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    answer = uiconfirm(app.UIFigure, 'You are not authorized to upload the dataset yet', 'Keep Uploading?', 'Options', {'Yes', 'No'} );
                    if strcmp(answer, 'No')
                        app.FinishState = "Cancel";
                    end
                end
                uiresume(app.UIFigure)
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LoginTab = uitab(app.TabGroup);
            app.LoginTab.Title = 'Login';
            app.GridLayout2 = uigridlayout(app.LoginTab);
            app.LoginLabel = uilabel(app.GridLayout2);
            app.LoginLabel.Text = 'Login';
            app.EmailEditField = uieditfield(app.GridLayout2, 'text');
            app.EmailEditField.Tooltip = {'If multiple, separate by comma '};
            app.EmailEditFieldLabel = uilabel(app.GridLayout2);
            app.EmailEditFieldLabel.Text = 'Email';
            app.PasswordEditFieldLabel = uilabel(app.GridLayout2);
            app.PasswordEditFieldLabel.Text = 'Password';
            app.PasswordEditField = uieditfield(app.GridLayout2, 'text');
            app.PasswordEditField.Tooltip = {'If multiple, separate by comma '};
            app.LoginButton = uibutton(app.GridLayout2, 'push');
            app.LoginButton.ButtonPushedFcn = createCallbackFcn(app, @loginButtonPushed, true);
            app.LoginButton.Text = 'Log in';
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset';
            app.GridLayout = uigridlayout(app.DatasetTab);
            app.DatasetsLabel = uilabel(app.GridLayout);
            app.DatasetsLabel.Text = 'Datasets';
            app.EmailEditFieldLabel_2 = uilabel(app.GridLayout);
            app.EmailEditFieldLabel_2.Text = 'Which dataset are you updating? ';
            app.DatasetsListBox = uilistbox(app.GridLayout);
            app.DatasetsListBox.Items = {};
            app.DatasetsListBox.ValueChangedFcn = createCallbackFcn(app, @datasetsListBoxValueChanged, true);
            app.DatasetsListBox.Value = {};
            app.SelectButton = uibutton(app.GridLayout, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.NameEditFieldLabel = uilabel(app.GridLayout);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.GridLayout, 'text');
            app.NameEditField.Editable = 'off';
            app.BranchNameEditFieldLabel = uilabel(app.GridLayout);
            app.BranchNameEditFieldLabel.Text = 'Branch Name';
            app.BranchNameEditField = uieditfield(app.GridLayout, 'text');
            app.BranchNameEditField.Editable = 'off';
            app.AuthorsLabel = uilabel(app.GridLayout);
            app.AuthorsLabel.Text = 'Authors';
            app.AuthorsEditField = uieditfield(app.GridLayout, 'text');
            app.AuthorsEditField.Editable = 'off';
            app.AbstractTextAreaLabel = uilabel(app.GridLayout);
            app.AbstractTextAreaLabel.Text = 'Abstract';
            app.AbstractTextArea = uitextarea(app.GridLayout);
            app.AbstractTextArea.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end