classdef OrganizationForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        EnterOrganizationDetailsLabel  matlab.ui.control.Label
        Panel                          matlab.ui.container.Panel
        GridLayout3                    matlab.ui.container.GridLayout
        InstructionTextLabel           matlab.ui.control.Label
        SearchRoridButton              matlab.ui.control.Button
        RORIDEditFieldLabel            matlab.ui.control.Label
        RORIDEditField                 matlab.ui.control.EditField
        OrganizationNameEditField      matlab.ui.control.EditField
        FullNameLabel                  matlab.ui.control.Label
        GridLayout2                    matlab.ui.container.GridLayout
        CancelButton                   matlab.ui.control.Button
        CreateButton                   matlab.ui.control.Button
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
    
        function setOrganizationDetails(app, S)
            if isfield(S, 'fullName')
                app.OrganizationNameEditField.Value = S.fullName;
            end
            if isfield(S, 'digitalIdentifier')
                app.RORIDEditField.Value = S.digitalIdentifier;
            end
        end

        function S = getOrganizationDetails(app)
            S = struct;
            S.fullName = app.OrganizationNameEditField.Value;
            S.digitalIdentifier = app.RORIDEditField.Value;
        end

        function reset(app)
            app.OrganizationNameEditField.Value = '';
            app.RORIDEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end

        function setName(app, name)
            app.OrganizationNameEditField.Value = name;
        end

    end

    methods (Access = private)
        function org = testCreateOrganization(app, organizationName, RORId, StartDate, EndDate)
            try 
                ror = openminds.core.RORID('identifier',RORId);
                org = openminds.core.Organization('digitalIdentifier',ror,...
                    'fullName',organizationName);
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: OrganizationNameEditField
        function OrganizationNameChangedFcn(app, event)
            value = app.OrganizationNameEditField.Value;
        end

        % Value changed function: RORIDEditField
        function RORidValueChanged(app, event)
            value = app.RORIDEditField.Value;
            progressDialog = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', ...
                'Message', 'Updating name from RORID', 'Title', 'Please wait');
            [name, rorID] = ndi.database.metadata_app.internal.validation.checkValidRORID(value);
            delete(progressDialog)
            if (isempty(rorID))
                errMessage = sprintf('The entered value is not a valid RORID. For examples of valid RORID, please see this <a href=https://ror.readme.io/docs/ror-identifier-pattern>link</a>');
                uialert(app.UIFigure, errMessage, 'Invalid RORID', 'Interpreter', 'html')
            else
                app.OrganizationNameEditField.Value = name;
            end
        end

        % Callback function
        function StartdateEditFieldValueChanged(app, event)
            value = app.StartdateEditField.Value;
            % Check that date is formatted as 'YYYY-MM-DD'.
            try
                ndi.database.metadata_app.fun.mustBeValidDate(value)
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end

        % Callback function
        function enddateEditFieldValueChanged(app, event)
            value = app.enddateEditField.Value;
            % Check that date is formatted as 'YYYY-MM-DD'.
            try
                ndi.database.metadata_app.fun.mustBeValidDate(value)
            catch ME
                uialert(app.UIFigure, ME.message, 'Invalid input')
            end
        end

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            if isempty(app.RORIDEditField.Value)
                uialert(app.UIFigure, 'Please fill in the ROR id', 'Empty ROR id');
            else
                app.FinishState = "Save";
                app.UIFigureCloseRequest();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new organization?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: SearchRoridButton
        function SearchRoridButtonPushed(app, event)
            fullName = app.OrganizationNameEditField.Value;
            apiQueryUrl = ndi.database.metadata_app.fun.getRorIdSearchUrl(fullName);
            web(apiQueryUrl)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Create Organization';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.FullNameLabel = uilabel(app.GridLayout3);
            app.FullNameLabel.Text = 'Full Name';
            app.OrganizationNameEditField = uieditfield(app.GridLayout3, 'text');
            app.OrganizationNameEditField.ValueChangedFcn = createCallbackFcn(app, @OrganizationNameChangedFcn, true);
            app.OrganizationNameEditField.Tooltip = {'Enter the full name of this organization.'};
            app.RORIDEditField = uieditfield(app.GridLayout3, 'text');
            app.RORIDEditField.ValueChangedFcn = createCallbackFcn(app, @RORidValueChanged, true);
            app.RORIDEditField.Tooltip = {'Enter the identifier for research organizations provided by the Corporation for National Research Initiatives (''Research Organization Registry IDentifier''; RORID)'};
            app.RORIDEditFieldLabel = uilabel(app.GridLayout3);
            app.RORIDEditFieldLabel.Text = 'ROR ID';
            app.SearchRoridButton = uibutton(app.GridLayout3, 'push');
            app.SearchRoridButton.ButtonPushedFcn = createCallbackFcn(app, @SearchRoridButtonPushed, true);
            app.SearchRoridButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearchRoridButton.Tooltip = {'Search the RORID database for an organization with the name entered in "Full Name"'};
            app.SearchRoridButton.Text = '';
            app.InstructionTextLabel = uilabel(app.GridLayout3);
            app.InstructionTextLabel.Text = 'Enter a part of the institution name and press the magnifying glass to search for the ROR identifier.';
            app.EnterOrganizationDetailsLabel = uilabel(app.GridLayout);
            app.EnterOrganizationDetailsLabel.Text = 'Enter Organization Details';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = OrganizationForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end