classdef PublicationForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        EnterPublicationDetailsLabel  matlab.ui.control.Label
        Panel                         matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        SearcDOIButton                matlab.ui.control.Button
        PMCIDEditField                matlab.ui.control.EditField
        PMCIDEditFieldLabel           matlab.ui.control.Label
        PMIDEditField                 matlab.ui.control.EditField
        PMIDEditFieldLabel            matlab.ui.control.Label
        InstructionTextLabel          matlab.ui.control.Label
        SearchFullNameButton          matlab.ui.control.Button
        DOIEditFieldLabel             matlab.ui.control.Label
        DOIEditField                  matlab.ui.control.EditField
        PublicationNameEditField      matlab.ui.control.EditField
        FullNameLabel                 matlab.ui.control.Label
        GridLayout2                   matlab.ui.container.GridLayout
        CancelButton                  matlab.ui.control.Button
        SaveButton                    matlab.ui.control.Button
    end

    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        % IsEditing : Flag indicating whether a previously created 
        % instance/struct is being edited. Currently not used in this 
        % class, but needed to fit the general pattern of a "Form". 
        % See example in StrainForm for how this is intended being used.
        IsEditing (1,1) logical = false

        % IsStandalone : Flag indicating whether app is opened 
        % independently or from another app (i.e NDI Dataset Uploader)
        IsStandalone (1,1) logical = true 
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
        
        function setFormData(app, S, editExisting)
            if nargin < 3; editExisting = true; end
            app.IsEditing = editExisting;

            if isfield(S, 'title')
                app.PublicationNameEditField.Value = S.title;
            end
            if isfield(S, 'doi')
                app.DOIEditField.Value = S.doi;
            end
            if isfield(S, 'pmid')
                app.PMIDEditField.Value = S.pmid;
            end
            if isfield(S, 'pmcid')
                app.PMCIDEditField.Value = S.pmcid;
            end
        end

        function S = getFormData(app)
            S = struct;
            S.title = app.PublicationNameEditField.Value;
            S.doi = app.DOIEditField.Value;
            S.pmid = app.PMIDEditField.Value;
            S.pmcid = app.PMCIDEditField.Value;
        end

        function reset(app)
            app.PublicationNameEditField.Value = '';
            app.DOIEditField.Value = '';
            app.PMIDEditField.Value = '';
            app.PMCIDEditField.Value = '';
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.FinishState = "Save";
            app.UIFigureCloseRequest();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save publication details?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: SearchFullNameButton
        function SearchFullNameButtonPushed(app, event)
            % todo
        end

        % Button pushed function: SearcDOIButton
        function SearcDOIButtonPushed(app, event)
            doi = app.DOIEditField.Value;
            try
                progressDialog = uiprogressdlg(app.UIFigure, 'Indeterminate', 'on', ...
                    'Message', 'Searching...', 'Title', 'Please Wait');
                publicationInfo = ndi.database.metadata_app.fun.resolveRelatedPublication(doi);
                delete(progressDialog)

            catch ME
                delete(progressDialog)
                uialert(app.UIFigure, ME.message, "Something went wrong")
                return
            end
            app.PMIDEditField.Value = publicationInfo.pmid;
            app.PMCIDEditField.Value = publicationInfo.pmcid;
            app.PublicationNameEditField.Value = publicationInfo.title;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Enter Publication Info';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.Panel);
            app.FullNameLabel = uilabel(app.GridLayout3);
            app.FullNameLabel.Text = 'Title';
            app.PublicationNameEditField = uieditfield(app.GridLayout3, 'text');
            app.PublicationNameEditField.Tooltip = {'Enter the title of this publication.'};
            app.DOIEditField = uieditfield(app.GridLayout3, 'text');
            app.DOIEditField.Tooltip = {'Enter the digital identifier for this publication'};
            app.DOIEditFieldLabel = uilabel(app.GridLayout3);
            app.DOIEditFieldLabel.Text = 'DOI';
            app.SearchFullNameButton = uibutton(app.GridLayout3, 'push');
            app.SearchFullNameButton.ButtonPushedFcn = createCallbackFcn(app, @SearchFullNameButtonPushed, true);
            app.SearchFullNameButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearchFullNameButton.Visible = 'off';
            app.SearchFullNameButton.Tooltip = {'Search the PubMed database for a publication with the name entered in "Title"'};
            app.SearchFullNameButton.Text = '';
            app.InstructionTextLabel = uilabel(app.GridLayout3);
            app.InstructionTextLabel.Text = 'Enter the DOI of a publication and click the magnifying glass to search for the publication on PubMed';
            app.PMIDEditFieldLabel = uilabel(app.GridLayout3);
            app.PMIDEditFieldLabel.Text = 'PMID';
            app.PMIDEditField = uieditfield(app.GridLayout3, 'text');
            app.PMIDEditField.Tooltip = {'PubMed ID'};
            app.PMCIDEditFieldLabel = uilabel(app.GridLayout3);
            app.PMCIDEditFieldLabel.Text = 'PMCID';
            app.PMCIDEditField = uieditfield(app.GridLayout3, 'text');
            app.PMCIDEditField.Tooltip = {'PubMed Central ID'};
            app.SearcDOIButton = uibutton(app.GridLayout3, 'push');
            app.SearcDOIButton.ButtonPushedFcn = createCallbackFcn(app, @SearcDOIButtonPushed, true);
            app.SearcDOIButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearcDOIButton.Tooltip = {'Search the PubMed database for a publication with the entered DOI'};
            app.SearcDOIButton.Text = '';
            app.EnterPublicationDetailsLabel = uilabel(app.GridLayout);
            app.EnterPublicationDetailsLabel.Text = 'Enter Publication Details';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PublicationForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end