classdef StrainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EnterStrainDetailsLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        FormGridLayout                  matlab.ui.container.GridLayout
        BackgroundStrainDropDownLabel   matlab.ui.control.Label
        DiseaseModelDropDown            matlab.ui.control.DropDown
        DiseaseModelDropDownLabel       matlab.ui.control.Label
        SynonymsEditFieldLabel          matlab.ui.control.Label
        OntologyIdentifierLabel         matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        StockNumberEditFieldLabel       matlab.ui.control.Label
        LaboratoryCodeEditFieldLabel    matlab.ui.control.Label
        PhenotypeEditFieldLabel         matlab.ui.control.Label
        BreedingTypeDropDownLabel       matlab.ui.control.Label
        AlternateIdentifierEditFieldLabel  matlab.ui.control.Label
        SynonymsEditField               matlab.ui.control.EditField
        OntologyIdentifierEditField     matlab.ui.control.EditField
        AlternateIdentifierEditField    matlab.ui.control.EditField
        SearcIdentifierButton_3         matlab.ui.control.Button
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        GeneticStrainTypeDropDownLabel  matlab.ui.control.Label
        StockNumberEditField            matlab.ui.control.EditField
        LaboratoryCodeEditField         matlab.ui.control.EditField
        BackgroundStrainDropDown2       matlab.ui.control.DropDown
        BackgroundStrainDropDown1       matlab.ui.control.DropDown
        InstructionTextLabel            matlab.ui.control.Label
        BreedingTypeDropDown            matlab.ui.control.DropDown
        PhenotypeEditField              matlab.ui.control.EditField
        GeneticStrainTypeDropDown       matlab.ui.control.DropDown
        AddSpeciesButton                matlab.ui.control.Button
        SpeciesDropDown                 matlab.ui.control.DropDown
        NameEditField                   matlab.ui.control.EditField
        SpeciesDropDownLabel            matlab.ui.control.Label
        FullNameLabel                   matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        DiseaseMenu                     matlab.ui.container.Menu
        DiseaseModelMenu                matlab.ui.container.Menu
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        PropertyNames (1,:) string
        Data (1,1) struct
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        SpeciesInstances (:,1) struct
        StrainInstances (:,1) struct
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        UIForm (1,1) struct
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)

        function setFormData(app, S)
            components = app.FormGridLayout.Children;

            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button')
                    continue
                end

                propertyName = components(i).Tag;
                if isfield(S, propertyName)
                    if strcmp(propertyName, 'backgroundStrain')
                        if components(i) == app.BackgroundStrainEditField
                            components(i).Value = S.(propertyName)(1);
                        elseif components(i) == app.BackgroundStrainEditField_2
                            components(i).Value = S.(propertyName)(2);
                        end
                    else
                        components(i).Value = S.(propertyName);
                    end
                end
            end
        end

        function S = getFormData(app)
            S = struct;

            components = app.FormGridLayout.Children;
            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button')
                    continue
                elseif isempty(components(i).Tag)
                    continue
                else
                    propertyName = components(i).Tag;
                    if strcmp(propertyName, 'backgroundStrain')
                        if ~isfield(S, propertyName)
                            data = "";
                        else
                            data = S.(propertyName);
                        end
                        if components(i) == app.BackgroundStrainDropDown1
                            data(1) = components(i).Value;
                        elseif components(i) == app.BackgroundStrainDropDown2
                            data(2) = components(i).Value;
                        end
                        S.(propertyName) = data;
                    else
                        propertyName = components(i).Tag;
                        S.(propertyName) = components(i).Value;
                    end
                end
            end
        end

        function reset(app)
            % % % components = app.FormGridLayout.Children;
            % % % 
            % % % for i = 1:numel(components)
            % % %     if isa(components(i), 'matlab.ui.control.Label') || ...
            % % %             isa(components(i), 'matlab.ui.control.Button')
            % % %         continue
            % % %     end
            % % %     components(i).Value = '';
            % % % end

            function resetField(component, ~)
                if isa(component,'matlab.ui.control.DropDown')
                    app.resetDropdownValue(component)
                elseif isa(component, 'matlab.ui.control.Button') || ...
                    isa(component, 'matlab.ui.control.StateButton')
                    % pass
                else
                    component.Value = "";
                end
            end
            app.doForInputFields(@resetField, [])
            app.FinishState = "";
        end

        function data = doForInputFields(app, functionHandle, data)
            components = app.FormGridLayout.Children;

            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button')
                    continue
                else
                    if nargout == 1
                        data = functionHandle(components(i), data);
                    else
                        disp(components(i).Tag)
                        functionHandle(components(i), data);
                    end
                end
                %components(i).Value = '';
            end

            if ~nargout; clear data; end
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end

    methods (Access = private)
        function strainInstances = getStrainInstances(app)
            import ndi.database.metadata_app.fun.getOpenmindsInstanceFile

            filename = sprintf('%s_instances.mat', 'strain');
            filePath = getOpenmindsInstanceFile(filename);
            strainInstances = PersistentCatalog("SaveFolder", filePath);
            strainInstances.NameField = 'name';
        end
        
        function S = openForm(app, formName, S)
        %openForm Open form where user can enter details
            
            if nargin < 3; S = struct.empty; end

            progressDialog = uiprogressdlg(app.UIFigure, ...
                'Message', sprintf('Opening form for entering %s details', formName), ...
                'Title', 'Please wait...', 'Indeterminate', "on");
        
            if ~isfield(app.UIForm, formName) || ~isvalid(app.UIForm.(formName))
                appPackage = 'ndi.database.metadata_app.Apps';
                formAppName = sprintf('%s.%sForm', appPackage, formName);
                app.UIForm.(formName) = feval(formAppName); % Create the form
            else
                app.UIForm.(formName).Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(S)
                % Initialize form if details exist and should be edited
                app.UIForm.(formName).setFormData(S);
            end
                
            ndi.database.metadata_app.utility.centerFigure(...
                app.UIForm.(formName).UIFigure, app.UIFigure, [20,-20])
            
            progressDialog.Message = sprintf('Enter %s details...', lower(formName));
            app.UIForm.(formName).waitfor(); % Wait for user to proceed

            % Get user-inputs from form
            S = app.UIForm.(formName).getFormData();
           
            % Update data in table if user pressed save.
            mode = app.UIForm.(formName).FinishState;
            if mode == "Save"
                % pass
            else
                S = struct.empty;
            end
            
            try
                app.UIForm.(formName).reset()
                app.UIForm.(formName).Visible = 'off'; % Hide the form (for later reuse
            catch
                delete(app.UIForm.(formName))
            end
            delete(progressDialog)
        end
    
        function resetDropdownValue(~, component)
            if component.Editable
                itemsData = component.ItemsData;
                component.ItemsData = [];
                component.Value = "";
                component.ItemsData = itemsData;
            else
                itemsData = component.ItemsData;
                component.ItemsData = [];
                component.Value = component.Items{1};
                component.ItemsData = itemsData;
            end
        end

        function updateBackgrounStrainDropdown(app, strainCatalog)
            items = string( {strainCatalog(:).name} );

            [items, itemsData] = ndi.database.metadata_app.fun.expandDropDownItems(items', items', 'Strain');
            app.BackgroundStrainDropDown1.Items = items;
            app.BackgroundStrainDropDown1.ItemsData = itemsData;
            app.BackgroundStrainDropDown2.Items = items;
            app.BackgroundStrainDropDown2.ItemsData = itemsData;
        end

        function addBackgroundStrain(app, event)
            S = app.openForm("Strain");
            if isempty(S)
                app.resetDropDownValue(event.Source)
                drawnow
            else
                strainInstances = getStrainInstances(app);
                try
                    strainInstances.add(S)
                    strainInstances.save()
                    app.updateBackgrounStrainDropdown(strainInstances)
                    event.Source.Value = S.name;
                catch ME
                    if ME.identifier == "Catalog:NamedItemExists"
                        uialert(app.UIFigure, sprintf("A strain with the name '%s' already exists", S.name), 'Strain Exists')
                    else
                        uialert(app.UIFigure, ME.message, 'Aborted')
                    end
                end
            end                        
        end

        function addSpecies(app, event)
            form = ndi.database.metadata_app.Apps.SpeciesForm();
            form.waitfor()

            if form.FinishState == "Save"
                S = form.getInfo;
                event.Source.ItemsData{end+1} = S.name;
                event.Source.Items{end+1} = S.name;
                event.Source.Value = S.name;
            else
                app.resetDropDownValue(event.Source)
                drawnow
            end
            delete(form)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            app.PropertyNames = properties(openminds.core.Strain);

            %Add tooltips for all the fields:
            mc = ?openminds.core.Strain;

            components = app.FormGridLayout.Children;
            for i = 1:numel(components)
                isMatch = strcmp({mc.PropertyList.Name}, components(i).Tag);
                if any(isMatch)
                    components(i).Tooltip = mc.PropertyList(isMatch).Description;
                end
            end

            [names, options] = ndi.database.metadata_app.fun.getOpenMindsInstances('Species', true);
            app.SpeciesDropDown.Placeholder = "Select a Species";
            app.SpeciesDropDown.Value = "";
            app.SpeciesDropDown.Items = options;
            app.SpeciesDropDown.ItemsData = names;

            [geneticStrainType, options] = ndi.database.metadata_app.fun.getOpenMindsInstances('GeneticStrainType', true);
            app.GeneticStrainTypeDropDown.Items = options;
            app.GeneticStrainTypeDropDown.ItemsData = geneticStrainType;

            [breedingType, options] = ndi.database.metadata_app.fun.getOpenMindsInstances('BreedingType', true);
            app.BreedingTypeDropDown.Items = options;
            app.BreedingTypeDropDown.ItemsData = breedingType;

            [diseaseModel, labels] = ndi.database.metadata_app.fun.getOpenMindsInstances('DiseaseModel', true);
            app.DiseaseModelDropDown.Items = labels;
            app.DiseaseModelDropDown.ItemsData = diseaseModel;

            strainInstances = getStrainInstances(app);
            app.updateBackgrounStrainDropdown(strainInstances)
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            app.FinishState = "Save";
            if app.IsStandalone
                disp(app.getFormData)
            end
            app.UIFigureCloseRequest();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save strain details?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Callback function
        function SearchFullNameButtonPushed(app, event)
            % todo
        end

        % Button pushed function: AddSpeciesButton
        function AddSpeciesButtonPushed(app, event)
            pos = getpixelposition(event.Source, true);
            app.ContextMenu.open(pos(1), pos(2))
        end

        % Value changed function: NameEditField
        function generalValueChanged(app, event)
            %fieldName = event.Source.Tag;
            %app.Data.(fieldName) = event.Value;
        end

        % Value changed function: BackgroundStrainDropDown1, 
        % ...and 2 other components
        function dropdownValueChanged(app, event)
            value = event.Value;
            if ismissing(value)
                itemsData = event.Source.ItemsData;
                event.Source.ItemsData = [];
                event.Source.Value = "";
                event.Source.ItemsData = itemsData;
            end

            if value == "create new"
                % % itemsData = event.Source.ItemsData;
                % % items = event.Source.Items;
                % % event.Source.ItemsData = [];
                % % event.Source.Items = sprintf("creating new %s...", event.Source.Tag);
                % % event.Source.Value = sprintf("creating new %s...", event.Source.Tag);
                % % event.Source.ItemsData = itemsData;
                % % event.Source.Items = items;
                % % drawnow

                switch event.Source.Tag
                    case 'backgroundStrain'
                        app.addBackgroundStrain(event)
                    case 'species'
                        app.addSpecies(event)
                end
            end

            %fieldName = event.Source.Tag;
            %app.Data.(fieldName) = event.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Enter Strain Info';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.FormGridLayout = uigridlayout(app.Panel);
            app.FullNameLabel = uilabel(app.FormGridLayout);
            app.FullNameLabel.Tag = 'name';
            app.FullNameLabel.Text = 'Name';
            app.SpeciesDropDownLabel = uilabel(app.FormGridLayout);
            app.SpeciesDropDownLabel.Tag = 'species';
            app.SpeciesDropDownLabel.Text = 'Species';
            app.NameEditField = uieditfield(app.FormGridLayout, 'text');
            app.NameEditField.ValueChangedFcn = createCallbackFcn(app, @generalValueChanged, true);
            app.NameEditField.Tag = 'name';
            app.NameEditField.Tooltip = {'Enter the title of this publication.'};
            app.SpeciesDropDown = uidropdown(app.FormGridLayout);
            app.SpeciesDropDown.Editable = 'on';
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.SpeciesDropDown.Tag = 'species';
            app.AddSpeciesButton = uibutton(app.FormGridLayout, 'push');
            app.AddSpeciesButton.ButtonPushedFcn = createCallbackFcn(app, @AddSpeciesButtonPushed, true);
            app.AddSpeciesButton.Tooltip = {'Add a new species'};
            app.AddSpeciesButton.Text = 'o';
            app.GeneticStrainTypeDropDown = uidropdown(app.FormGridLayout);
            app.GeneticStrainTypeDropDown.Editable = 'on';
            app.GeneticStrainTypeDropDown.Tag = 'geneticStrainType';
            app.PhenotypeEditField = uieditfield(app.FormGridLayout, 'text');
            app.PhenotypeEditField.Tag = 'phenotype';
            app.BreedingTypeDropDown = uidropdown(app.FormGridLayout);
            app.BreedingTypeDropDown.Editable = 'on';
            app.BreedingTypeDropDown.Tag = 'breedingType';
            app.BreedingTypeDropDown.Placeholder = 'select a Species';
            app.InstructionTextLabel = uilabel(app.FormGridLayout);
            app.InstructionTextLabel.Text = 'Description here';
            app.BackgroundStrainDropDown1 = uidropdown(app.FormGridLayout);
            app.BackgroundStrainDropDown1.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.BackgroundStrainDropDown1.Tag = 'backgroundStrain';
            app.BackgroundStrainDropDown2 = uidropdown(app.FormGridLayout);
            app.BackgroundStrainDropDown2.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.BackgroundStrainDropDown2.Tag = 'backgroundStrain';
            app.LaboratoryCodeEditField = uieditfield(app.FormGridLayout, 'text');
            app.LaboratoryCodeEditField.Tag = 'laboratoryCode';
            app.StockNumberEditField = uieditfield(app.FormGridLayout, 'text');
            app.StockNumberEditField.Tag = 'stockNumber';
            app.GeneticStrainTypeDropDownLabel = uilabel(app.FormGridLayout);
            app.GeneticStrainTypeDropDownLabel.Tag = 'geneticStrainType';
            app.GeneticStrainTypeDropDownLabel.Text = 'Genetic Strain Type';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.FormGridLayout);
            app.DigitalIdentifierEditFieldLabel.Tag = 'digitalIdentifier';
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.DigitalIdentifierEditField.Tag = 'digitalIdentifier';
            app.SearcIdentifierButton_3 = uibutton(app.FormGridLayout, 'push');
            app.SearcIdentifierButton_3.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearcIdentifierButton_3.Tooltip = {'Search the PubMed database for a publication with the entered DOI'};
            app.SearcIdentifierButton_3.Text = '';
            app.AlternateIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.AlternateIdentifierEditField.Tag = 'alternateIdentifier';
            app.OntologyIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.OntologyIdentifierEditField.Tag = 'ontologyIdentifier';
            app.SynonymsEditField = uieditfield(app.FormGridLayout, 'text');
            app.SynonymsEditField.Tag = 'synonym';
            app.AlternateIdentifierEditFieldLabel = uilabel(app.FormGridLayout);
            app.AlternateIdentifierEditFieldLabel.Tag = 'alternateIdentifier';
            app.AlternateIdentifierEditFieldLabel.Text = 'Alternate Identifier';
            app.BreedingTypeDropDownLabel = uilabel(app.FormGridLayout);
            app.BreedingTypeDropDownLabel.Tag = 'breedingType';
            app.BreedingTypeDropDownLabel.Text = 'Breeding Type';
            app.PhenotypeEditFieldLabel = uilabel(app.FormGridLayout);
            app.PhenotypeEditFieldLabel.Tag = 'phenotype';
            app.PhenotypeEditFieldLabel.Text = 'Phenotype';
            app.LaboratoryCodeEditFieldLabel = uilabel(app.FormGridLayout);
            app.LaboratoryCodeEditFieldLabel.Tag = 'laboratoryCode';
            app.LaboratoryCodeEditFieldLabel.Text = 'Laboratory Code';
            app.StockNumberEditFieldLabel = uilabel(app.FormGridLayout);
            app.StockNumberEditFieldLabel.Tag = 'stockNumber';
            app.StockNumberEditFieldLabel.Text = 'Stock Number';
            app.DescriptionTextAreaLabel = uilabel(app.FormGridLayout);
            app.DescriptionTextAreaLabel.Tag = 'description';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.FormGridLayout);
            app.DescriptionTextArea.Tag = 'description';
            app.OntologyIdentifierLabel = uilabel(app.FormGridLayout);
            app.OntologyIdentifierLabel.Tag = 'ontologyIdentifier';
            app.OntologyIdentifierLabel.Text = 'Ontology Identifier';
            app.SynonymsEditFieldLabel = uilabel(app.FormGridLayout);
            app.SynonymsEditFieldLabel.Tag = 'synonym';
            app.SynonymsEditFieldLabel.Text = 'Synonyms';
            app.DiseaseModelDropDownLabel = uilabel(app.FormGridLayout);
            app.DiseaseModelDropDownLabel.Tag = 'diseaseModel';
            app.DiseaseModelDropDownLabel.Text = 'Disease Model';
            app.DiseaseModelDropDown = uidropdown(app.FormGridLayout);
            app.DiseaseModelDropDown.Tag = 'diseaseModel';
            app.BackgroundStrainDropDownLabel = uilabel(app.FormGridLayout);
            app.BackgroundStrainDropDownLabel.Text = 'Background Strain';
            app.EnterStrainDetailsLabel = uilabel(app.GridLayout);
            app.EnterStrainDetailsLabel.Text = 'Enter Strain Details';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DiseaseMenu = uimenu(app.ContextMenu);
            app.DiseaseMenu.Text = 'Disease';
            app.DiseaseModelMenu = uimenu(app.ContextMenu);
            app.DiseaseModelMenu.Text = 'DiseaseModel';
            % Assign app.ContextMenu
            app.EnterStrainDetailsLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrainForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end