classdef SpeciesForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        GridLayout3                  matlab.ui.container.GridLayout
        DefinitionTextAreaLabel      matlab.ui.control.Label
        NameLabel                    matlab.ui.control.Label
        PreferredOntologyIdentifierLabel  matlab.ui.control.Label
        DescriptionTextArea          matlab.ui.control.TextArea
        DescriptionTextAreaLabel     matlab.ui.control.Label
        DefinitionTextArea           matlab.ui.control.TextArea
        SynonymEditField             matlab.ui.control.EditField
        SynonymEditFieldLabel        matlab.ui.control.Label
        OntologyIdentifierEditField  matlab.ui.control.EditField
        NameEditField                matlab.ui.control.EditField
        CreateSpeciesMetadataLabel   matlab.ui.control.Label
        GridLayout2                  matlab.ui.container.GridLayout
        CancelButton                 matlab.ui.control.Button
        CreateButton                 matlab.ui.control.Button
    end

    
    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end

    methods % Methods for setting or getting values from components

        function resetComponents(app)
            app.NameEditField.Value = '';
            app.OntologyIdentifierEditField.Value = '';
            app.SynonymEditField.Value = '';
            app.DefinitionTextArea.Value = '';
            app.DescriptionTextArea.Value = '';
        end

        function setName(app, name)
            app.NameEditField.Value = name;
        end

        function setInfo(app, S)
            app.NameEditField.Value = S.name;
            app.OntologyIdentifierEditField.Value = S.ontologyIdentifier;
            app.SynonymEditField.Value = strjoin(S.synonyms, ', ');
            app.DefinitionTextArea.Value = S.definition;
            app.DescriptionTextArea.Value = S.description;
        end

        function S = getInfo(app)
            S = struct;
            S.name = app.NameEditField.Value;
            S.ontologyIdentifier = app.OntologyIdentifierEditField.Value;
            S.synonyms = strtrim( string( strsplit(app.SynonymEditField.Value, ',') ) );
            S.definition = app.DefinitionTextArea.Value;
            S.description = app.DescriptionTextArea.Value;
        end

    end

    methods (Access = public)
    
        function reset(app)
            app.resetComponents()
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CreateButton
        function CreateButtonPushed(app, event)
            app.FinishState = "Save";
            app.UIFigureCloseRequest();
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save the new species?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Edit Species Details';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CreateButton = uibutton(app.GridLayout2, 'push');
            app.CreateButton.ButtonPushedFcn = createCallbackFcn(app, @CreateButtonPushed, true);
            app.CreateButton.Text = 'Create';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CreateSpeciesMetadataLabel = uilabel(app.GridLayout);
            app.CreateSpeciesMetadataLabel.Text = 'Create Species Metadata';
            app.GridLayout3 = uigridlayout(app.GridLayout);
            app.NameEditField = uieditfield(app.GridLayout3, 'text');
            app.OntologyIdentifierEditField = uieditfield(app.GridLayout3, 'text');
            app.OntologyIdentifierEditField.Placeholder = 'accept URLs only';
            app.SynonymEditFieldLabel = uilabel(app.GridLayout3);
            app.SynonymEditFieldLabel.Text = 'Synonym';
            app.SynonymEditField = uieditfield(app.GridLayout3, 'text');
            app.DefinitionTextArea = uitextarea(app.GridLayout3);
            app.DescriptionTextAreaLabel = uilabel(app.GridLayout3);
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.GridLayout3);
            app.PreferredOntologyIdentifierLabel = uilabel(app.GridLayout3);
            app.PreferredOntologyIdentifierLabel.Text = 'Preferred Ontology Identifier';
            app.NameLabel = uilabel(app.GridLayout3);
            app.NameLabel.Text = 'Name';
            app.DefinitionTextAreaLabel = uilabel(app.GridLayout3);
            app.DefinitionTextAreaLabel.Text = 'Definition';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SpeciesForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end