classdef FolderOrganizationToolbar < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout                   matlab.ui.container.GridLayout
        PreviewButton                matlab.ui.control.Button
        ShowFiltersButton            matlab.ui.control.StateButton
        SelectTemplateDropDown       matlab.ui.control.DropDown
        MenuButton                   matlab.ui.control.Button
        SelectTemplateDropDownLabel  matlab.ui.control.Label
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ShowFiltersToggle
    end

    properties (Access = public)
        MenuButtonPushedFcn
        PresetFolderModels (1,:) string
        PreviewButtonPushedFcn
        SelectTemplateDropDownValueChangedFcn
        ShowFiltersButtonPushedFcn
        Theme (1,1) % A color theme to use for components
    end
    
    methods
        function set.Theme(comp, newValue)
            comp.Theme = newValue;
            comp.onThemeSet()
        end

        function set.MenuButtonPushedFcn(comp, value)
            comp.MenuButtonPushedFcn = value;
            comp.MenuButton.Enable = ...
                ~isempty(comp.MenuButtonPushedFcn); %#ok<MCSUP>
        end
        
        function set.PreviewButtonPushedFcn(comp, value)
            comp.PreviewButtonPushedFcn = value;
            comp.PreviewButton.Enable = ...
                ~isempty(comp.PreviewButtonPushedFcn); %#ok<MCSUP>
        end

        function set.SelectTemplateDropDownValueChangedFcn(comp, value)
            comp.SelectTemplateDropDownValueChangedFcn = value;
            comp.SelectTemplateDropDown.Enable = ...
                ~isempty(comp.SelectTemplateDropDownValueChangedFcn); %#ok<MCSUP>
        end

        function set.ShowFiltersButtonPushedFcn(comp, value)
            comp.ShowFiltersButtonPushedFcn = value;
            comp.ShowFiltersButton.Enable = ...
                ~isempty(comp.ShowFiltersButtonPushedFcn); %#ok<MCSUP>
        end

        function set.PresetFolderModels(comp, value)
            items = ["Select a template", value];
            comp.SelectTemplateDropDown.Items = items; %#ok<MCSUP>
        end
    end

    methods (Access = private)
        function onThemeSet(comp)
            comp.GridLayout.BackgroundColor = comp.Theme.FigureBgColor;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SelectTemplateDropDown
        function SelectTemplateDropDownValueChanged(comp, event)
            if ~isempty( comp.SelectTemplateDropDownValueChangedFcn )
                if strcmp( event.Value, "Select a template")
                    return
                else
                    comp.SelectTemplateDropDownValueChangedFcn(event)
                end
            end
        end

        % Button pushed function: PreviewButton
        function PreviewButtonPushed(comp, event)
            if ~isempty( comp.PreviewButtonPushedFcn )
                comp.PreviewButtonPushedFcn(event)
            end
        end

        % Value changed function: ShowFiltersButton
        function ShowFiltersButtonPushed(comp, event)
            if ~isempty( comp.ShowFiltersButtonPushedFcn )
                if event.Value
                    event.Source.Text = 'Hide Filters';
                else
                    event.Source.Text = 'Show Filters';
                end
                comp.ShowFiltersButtonPushedFcn(event)
            end
        end

        % Button pushed function: MenuButton
        function MenuButtonPushed(comp, event)
            if ~isempty( comp.MenuButtonPushedFcn )
                comp.MenuButtonPushedFcn(event)
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 715 24];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.GridLayout = uigridlayout(comp);
            comp.SelectTemplateDropDownLabel = uilabel(comp.GridLayout);
            comp.SelectTemplateDropDownLabel.Text = 'Select Template';
            comp.MenuButton = uibutton(comp.GridLayout, 'push');
            comp.MenuButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @MenuButtonPushed, true);
            comp.MenuButton.Enable = 'off';
            comp.MenuButton.Visible = 'off';
            comp.MenuButton.Text = '...';
            comp.SelectTemplateDropDown = uidropdown(comp.GridLayout);
            comp.SelectTemplateDropDown.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @SelectTemplateDropDownValueChanged, true);
            comp.SelectTemplateDropDown.Enable = 'off';
            comp.ShowFiltersButton = uibutton(comp.GridLayout, 'state');
            comp.ShowFiltersButton.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @ShowFiltersButtonPushed, true);
            comp.ShowFiltersButton.Enable = 'off';
            comp.ShowFiltersButton.Text = 'Show Filters';
            comp.PreviewButton = uibutton(comp.GridLayout, 'push');
            comp.PreviewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @PreviewButtonPushed, true);
            comp.PreviewButton.Text = 'Preview';
        end
    end
end