classdef DAQSystemEditButtons < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        ConfigureButton  IconButton
        EditButton       IconButton
        LoadButton       IconButton
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ButtonPushed
        ConfigureButtonPushed
        EditButtonPushed
        LoadButtonPushed
    end

    methods (Access = private)
        function updateBackgroundColor(comp)
            if isa(comp.Parent, 'matlab.ui.Figure')
                backgroundColor = comp.Parent.Color;
            else
                if isprop(comp.Parent, 'BackgroundColor')
                    backgroundColor = comp.Parent.BackgroundColor;
                else
                    S = warning('off', 'backtrace');
                    warning('Could not detect background color.')
                    warning(S);
                end
            end

            comp.LoadButton.BackgroundColor = backgroundColor;
            comp.EditButton.BackgroundColor = backgroundColor;
            comp.ConfigureButton.BackgroundColor = backgroundColor;

            comp.BackgroundColor = backgroundColor;
            %comp.GridLayout.BackgroundColor = backgroundColor;
        end

        function onParentPropertyChanged(comp, src, evt)
            if strcmp(evt.Property, 'BackgroundColor')
                comp.updateBackgroundColor()
            end
        end

        function onLoadButtonPushed(comp, ~, evt)
            notify(comp, 'LoadButtonPushed', evt);
            evt = ndi.dataset.gui.event.UserActionEventData('Load');
            notify(comp, 'ButtonPushed', evt);
        end
        function onEditButtonPushed(comp, ~, evt)
            notify(comp, 'EditButtonPushed', evt);
            evt = ndi.dataset.gui.event.UserActionEventData('Edit');
            notify(comp, 'ButtonPushed', evt);
        end
        function onConfigureButtonPushed(comp, ~, evt)
            notify(comp, 'ConfigureButtonPushed', evt);
            evt = ndi.dataset.gui.event.UserActionEventData('Configure');
            notify(comp, 'ButtonPushed', evt);
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.LoadButton.SVGSource = ndi.gui.utility.internal.getIconPath('upload');
            comp.EditButton.SVGSource = ndi.gui.utility.internal.getIconPath('edit');
            comp.ConfigureButton.SVGSource = ndi.gui.utility.internal.getIconPath('set-up');
            comp.LoadButton.Color = '';
            comp.EditButton.Color = '';
            comp.ConfigureButton.Color = '';
            comp.LoadButton.Size = [20,20];
            comp.EditButton.Size = [20,20];
            comp.ConfigureButton.Size = [20,20];
            comp.LoadButton.Tooltip = 'Load';
            comp.EditButton.Tooltip = 'Edit';
            comp.ConfigureButton.Tooltip = 'Configure';
            comp.LoadButton.Padding = 0;
            comp.EditButton.Padding = 0;
            comp.ConfigureButton.Padding = 0;

            comp.LoadButton.ButtonPushedFcn = @comp.onLoadButtonPushed;
            comp.EditButton.ButtonPushedFcn = @comp.onEditButtonPushed;
            comp.ConfigureButton.ButtonPushedFcn = @comp.onConfigureButtonPushed;

            comp.updateBackgroundColor()
            addlistener(comp.Parent, 'BindablePropertyChanged', @comp.onParentPropertyChanged);
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 85 25];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.LoadButton = IconButton(comp);
            comp.EditButton = IconButton(comp);
            comp.ConfigureButton = IconButton(comp);
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end