classdef WizardApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        MainGridLayout    matlab.ui.container.GridLayout
        HeaderGridLayout  matlab.ui.container.GridLayout
        HeaderLabel       matlab.ui.control.Label
        BodyGridLayout    matlab.ui.container.GridLayout
        LoadingIndicator  matlab.ui.control.HTML
        DescriptionLabel  matlab.ui.control.Label
        FooterGridLayout  matlab.ui.container.GridLayout
        NavBarPanel       matlab.ui.container.Panel
        NavButtonPanel    matlab.ui.container.Panel
        LogoImage         matlab.ui.control.Image
    end

    properties (Abstract, Access = protected)
        Title (1,1) string
        PageNames (1,:) string
    end

    properties (SetAccess = private)
        Pages (1,:) ndi.gui.window.wizard.abstract.Page
    end
    
    properties (Abstract, Constant, Access = protected)
        AppPath (1,1) string
        LogoFilePath (1,1) string
        MinimumSize
    end

    properties (Access = public)
        Theme %ndi.gui.window.wizard.abstract.Theme
    end
    
    properties (Access=private)
        CurrentPageNumber = 1
    end

    properties (Access=private) % Custom app components
        PageIndicator
        NextButton
        PreviousButton
    end

    methods (Access = protected)
        function loadTheme(app)
            % No theme.
            %app.Theme = ndi.dataset.gui.WizardTheme();
        end

        function onPageCreated(app, pageObject) %#ok<INUSD>
            % Subclasses may implement
        end

        function doAbort = onFigureCloseRequest(app, event)
            % Subclasses may implement
            doAbort = false;
        end
    end

    methods (Access = protected, Sealed)
        function pageObject = getPage(app, pageTitle)
            idx = strcmp([app.Pages.Title], pageTitle);
            pageObject = app.Pages(idx);
        end
    end

    methods (Access = private) % App creation
        function applyTheme(app)
            %app.HeaderPanel.BackgroundColor = app.Theme.HeaderBgColor;
            app.HeaderGridLayout.BackgroundColor = app.Theme.HeaderBgColor;
            %app.MainGridLayout.BackgroundColor = app.Theme.FigureBgColor;
            app.BodyGridLayout.BackgroundColor = app.Theme.FigureBgColor;

            app.NavButtonPanel.BackgroundColor = app.Theme.HeaderBgColor;
            app.NavBarPanel.BackgroundColor = app.Theme.HeaderBgColor;
            app.FooterGridLayout.BackgroundColor = app.Theme.HeaderBgColor;
            
            app.HeaderLabel.BackgroundColor = app.Theme.HeaderBgColor;
            app.HeaderLabel.FontColor = app.Theme.HeaderFgColor;
        end

        function initializePages(app)
            for pageName = app.PageNames
                app.Pages(end+1) = feval(pageName);
            end
        end

        function createFooter(app)
            if ~isempty( app.LogoFilePath )
                app.LogoImage.ImageSource = app.LogoFilePath;
            end
            app.createNavigationButtons()
            app.createPageSteps()
        end

        function createNavigationButtons(app)

            ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
            
            SPACING = 75;
            [H, W] = deal(60); 
            Y = 5;
            X = [1, 1+SPACING+W];

            app.PreviousButton = uim.control.Button_(app.NavButtonPanel, ...
                'Icon', ICONS.prev2, ...
                'Position', [X(1),Y,W,H], ...
                'Size', [W, H], ...
                'CornerRadius', W/2, ...
                'Padding', [10,10,10,10], ...
                'MechanicalAction', 'Latch when released', ...
                'Callback', @(src,evt) app.PreviousButtonPushed(evt), ...
                'Visible', 'on', ...
                'Style', ndi.gui.internal.theme.NDIButtonLight);
            
            app.NextButton = uim.control.Button_(app.NavButtonPanel, ...
                'Icon', ICONS.next2, ...
                'Position', [X(2),Y,W,H], ...
                'Size', [W, H], ...
                'CornerRadius', W/2, ...
                'Padding', [16,10,10,10], ...
                'MechanicalAction', 'Latch when released',...
                'Callback', @(src,evt) app.NextButtonPushed(evt), ...
                'Visible', 'on', ...
                'Style', ndi.gui.internal.theme.NDIButtonLight);
        end


        function createPageSteps(app)
            
            uicc = uim.UIComponentCanvas(app.NavBarPanel);

            options = {...
                           'Size', [inf, 60], ...
                         'Margin', [5, 3, 20, 10], ...
                       'Location', 'southwest', ...
                  'IndicatorSize', 10, ...
                       'SizeMode', 'manual', ...
                       'BarColor', app.Theme.FigureBgColor, ...
                      'FontColor', app.Theme.FigureBgColor, ...
                 'IndicatorColor', app.Theme.HeaderMidColor, ...
                       'FontSize', 14, ...
                  'IndicatorSize', 12, ...
                'BlockChangePage', true
            };

            numPages = numel(app.Pages);
            pageNames = cell(1, numPages);
            for i = 1:numPages
                pageNames{i} = app.Pages(i).Name;
            end

            app.PageIndicator = uim.widget.PageIndicator(uicc, pageNames, options{:});
            app.PageIndicator.ChangePageFcn = @app.onPageSwitcherPressed;
        end

        function initializeLoadingIndicator(app)
            rootPath = fileparts(mfilename('fullpath'));
            app.LoadingIndicator.HTMLSource = fullfile(rootPath, 'resources', 'html','emblem_pulse.html');
        end

        function showSpinner(app)
            %app.Image.Visible = 'on';
            app.LoadingIndicator.Visible = 'on';
        end

        function hideSpinner(app)
            %app.Image.Visible = 'off';
            app.LoadingIndicator.Visible = 'off';
        end

        function showNavigationButtons(app)
            app.NextButton.Visible = 'on';
            app.PreviousButton.Visible = 'on';
        end

        function hideNavigationButtons(app)
            app.NextButton.Visible = 'off';
            app.PreviousButton.Visible = 'off';
        end

        function setFigureMinSize(app)
            isMatch = false;
            drawnow
            app.UIFigure.Tag = ndi.fun.timestamp;

            while ~any(isMatch)
                windowList = matlab.internal.webwindowmanager.instance.findAllWebwindows();
                isMatch = strcmp({windowList.Title}, app.UIFigure.Name);
            end
            window = windowList(find(isMatch, 1, 'last'));
            window.setMinSize(app.MinimumSize)
        end
    end

    methods (Access = ?ndi.gui.window.wizard.abstract.Page) % App callbacks
        function changeWizardPage(app, old, new)
            app.changePage(old, new)
        end
    end

    methods (Access = private) % App callbacks
        function onPageSwitcherPressed(app, src, evt)
        %onPageSwitcherPressed Callback for clicks on pageswitcher bar    
            %   Initialize changing page
            app.changePage(evt.OldPageNumber, evt.NewPageNumber)
        end

        function NextButtonPushed(app, ~)
            app.onChangePagePressed('next')
        end

        function PreviousButtonPushed(app, ~)
            app.onChangePagePressed('previous')
        end

        function onChangePagePressed(app, direction)
        %onChangePagePressed Callback for clicks on next/previous buttons
        %
        %   Initialize changing page
        
            switch direction
                case 'previous'
                    incr = -1;
                case 'next'
                    incr = 1;
            end

            newPageNumber = app.CurrentPageNumber + incr;
            
            % Don't switch to tabs outside range
            if newPageNumber < 1 || newPageNumber > numel(app.Pages)
                return
            end

            app.changePage(app.CurrentPageNumber, newPageNumber)
        end

        function changePage(app, currentTabNum, newTabNum)
        %changePage Change tabs and do all the internal work needed.
        
            % Todo: Use tab numbers or tab titles as inputs?
            
            if ~isempty(currentTabNum)
                app.Pages(currentTabNum).leavePage()
            end

            newTitle = app.Pages(newTabNum).Title;
            app.HeaderLabel.Text = newTitle;
            app.DescriptionLabel.Text = sprintf(app.Pages(newTabNum).Description);
            
            app.CurrentPageNumber = newTabNum;
            app.PageIndicator.changePage(newTabNum)

            if ~app.Pages(newTabNum).IsInitialized
                app.showSpinner()
                app.Pages(newTabNum).initialize(app)
                app.hideSpinner()
                app.onPageCreated( app.Pages(newTabNum) )
            end

            app.Pages(newTabNum).enterPage()

            % Ensure all other pages are hidden.
            % Question: Change Interruptible for button press?
            for i = 1:numel(app.Pages)
                if i == newTabNum
                    continue
                else
                    app.Pages(i).hide()
                end
            end
            drawnow
            pause(0.01)

            if app.Pages(newTabNum).ShowNavigationButtons
                app.showNavigationButtons()
            else
                app.hideNavigationButtons()
            end

            return

            currentTabName = app.VisibleTabPages(currentTabNum);
            currentTab = app.(currentTabName);

            newTabName = app.VisibleTabPages(newTabNum);
            newTab = app.(newTabName);
            
            % Check if tab is completed and we can move on
            isCompleted = app.checkIfTabCompleted(currentTab.Title);
            if ~isCompleted
                return
            end
            
            app.exitTab(currentTab.Title)
            
            % Change the selected tab.
            app.TabGroup.SelectedTab = newTab;
            app.CurrentPageNumber = newTabNum;

            % Call method to update the selected tab.
            app.enterTab(newTab)
            
            app.PageIndicator.changePage(newTabNum)
            
            % Turn off navigation bar if going back to Welcome page 
            if newTabNum == 1
                app.NavigationPanel.Visible = 'on';
                app.NextButton.Visible = 'off';
                app.PreviousButton.Visible = 'off';
            else
                app.NavigationPanel.Visible = 'on';
                app.NextButton.Visible = 'on';
                app.PreviousButton.Visible = 'on';
            end
            
% %             if newTabNum == numel(app.VisibleTabPages)
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.finish;
% %             else
% %                 ICONS = uim.style.iconSet(applify.apptable1.getIconPath);
% %                 app.NextButton2.Icon = ICONS.next2;
% %             end
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            ndi.gui.utility.centerFigure(app.UIFigure)
            app.UIFigure.Name = app.Title;
            app.setFigureMinSize()

            app.loadTheme()
            app.applyTheme()
            
            app.initializeLoadingIndicator()

            app.initializePages()

            app.createFooter()

            app.changePage([], app.CurrentPageNumber)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
               
            app.Pages(app.CurrentPageNumber).leavePage()
            doAbort = app.onFigureCloseRequest(event);
            if doAbort
                app.Pages(app.CurrentPageNumber).enterPage()
            else
                delete(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.FooterGridLayout = uigridlayout(app.MainGridLayout);
            app.LogoImage = uiimage(app.FooterGridLayout);
            app.NavButtonPanel = uipanel(app.FooterGridLayout);
            app.NavBarPanel = uipanel(app.FooterGridLayout);
            app.BodyGridLayout = uigridlayout(app.MainGridLayout);
            app.DescriptionLabel = uilabel(app.BodyGridLayout);
            app.DescriptionLabel.Text = 'Description';
            app.LoadingIndicator = uihtml(app.BodyGridLayout);
            app.LoadingIndicator.HTMLSource = '/Users/Eivind/Code/MATLAB/Sandbox/Appdesign/Components/NDILoader/emblem_pulse.html';
            app.HeaderGridLayout = uigridlayout(app.MainGridLayout);
            app.HeaderLabel = uilabel(app.HeaderGridLayout);
            app.HeaderLabel.Text = 'Title';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = WizardApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end