classdef selectDaqSystemDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        MainGridLayout      matlab.ui.container.GridLayout
        TitleLabel          matlab.ui.control.Label
        DescriptionLabel    matlab.ui.control.Label
        ListBox             matlab.ui.control.ListBox
        GridLayoutLoad      matlab.ui.container.GridLayout
        LoadfromFileButton  matlab.ui.control.Button
        GridLayoutFooter    matlab.ui.container.GridLayout
        SelectButton        matlab.ui.control.Button
        CancelButton        matlab.ui.control.Button
    end

    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties 
        Selection (1,1) string
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        DaqSystemConfigurationFilePaths (1,:) cell
    end

    % Property set/get methods
    methods
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)
        function reset(app)
            % Reset form / controls
            % < Add code here >
            app.FinishState = "";
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end

        function selectedValues = getSelection(app)
            selectedValues = app.Selection;
            % Get filepath:
        end
    end
    
    methods (Access = private)
        function populateDaqSystemsList(app)
            [names, files] = ndi.setup.daq.system.listDaqSystemNames();
            app.DaqSystemConfigurationFilePaths = files;
            app.ListBox.Items = names;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.populateDaqSystemsList()
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            app.FinishState = "Save";
            selectionIndex = app.ListBox.ValueIndex;
            app.Selection = app.DaqSystemConfigurationFilePaths(selectionIndex);
            app.UIFigureCloseRequest()
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest()
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                if app.FinishState == ""
                    app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save selection?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                end
                uiresume(app.UIFigure)
            end
        end

        % Button pushed function: LoadfromFileButton
        function LoadfromFileButtonPushed(app, event)
            app.UIFigure.WindowStyle = "normal";
            [fileName, folderPath] = uigetfile('*.json','');
            app.UIFigure.WindowStyle = "alwaysontop";
            
            if fileName == 0; return; end

            % Todo: Validate file.
    
            app.Selection = fullfile(folderPath, fileName);
            app.FinishState = "Save";
            uiresume(app.UIFigure)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Select DAQ System';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainGridLayout = uigridlayout(app.UIFigure);
            app.GridLayoutFooter = uigridlayout(app.MainGridLayout);
            app.CancelButton = uibutton(app.GridLayoutFooter, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.SelectButton = uibutton(app.GridLayoutFooter, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.GridLayoutLoad = uigridlayout(app.MainGridLayout);
            app.LoadfromFileButton = uibutton(app.GridLayoutLoad, 'push');
            app.LoadfromFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadfromFileButtonPushed, true);
            app.LoadfromFileButton.Text = 'Load from File...';
            app.ListBox = uilistbox(app.MainGridLayout);
            app.ListBox.Multiselect = 'on';
            app.ListBox.Value = {'Item 1'};
            app.DescriptionLabel = uilabel(app.MainGridLayout);
            app.DescriptionLabel.Text = 'Select a preconfigured DAQ System configuration from the list below, or load a custom DAQ System configuration from file.';
            app.TitleLabel = uilabel(app.MainGridLayout);
            app.TitleLabel.Text = 'Select DAQ System Configuration';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = selectDaqSystemDialog

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end