classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        DATASETESSENTIALSLabel          matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        Tab                             matlab.ui.container.Tab
        EmptyAbstractWarning            matlab.ui.control.Image
        EmptyBranchTitleWarning         matlab.ui.control.Image
        SaveButton                      matlab.ui.control.Button
        CommentsAdditionalDetailsTextArea  matlab.ui.control.TextArea
        CommentsAdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        AbstractTextArea                matlab.ui.control.TextArea
        AbstractTextAreaLabel           matlab.ui.control.Label
        DatasetBranchTitleEditField     matlab.ui.control.EditField
        DatasetBranchTitleEditFieldLabel  matlab.ui.control.Label
        LicenseDropDown                 matlab.ui.control.DropDown
        LicenseDropDownLabel            matlab.ui.control.Label
        FundingLabel                    matlab.ui.control.Label
        FileSelector                    wt.FileSelector
        DatasetIdentifierEditField      matlab.ui.control.EditField
        DatasetIdentifierEditFieldLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Tab2                            matlab.ui.container.Tab
        EmptyRelatedPublicationsWarning  matlab.ui.control.Image
        EmptyAuthorWarning              matlab.ui.control.Image
        DeleteButton                    matlab.ui.control.Button
        AddAuthorButton                 matlab.ui.control.Button
        EmptyInstituteWarning           matlab.ui.control.Image
        EmptyDigitalIdentifierWarning   matlab.ui.control.Image
        EmptyEmailWarning               matlab.ui.control.Image
        EmptyFamilyNameWarning          matlab.ui.control.Image
        EmptyGivenNameWarning           matlab.ui.control.Image
        NumAuthorWarning                matlab.ui.control.Image
        InstitutesAffiliationsTextArea  matlab.ui.control.TextArea
        InstitutesAffiliationsTextAreaLabel  matlab.ui.control.Label
        RelatedPublicationsTextAreaLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        RelatedPublicationsTextArea     matlab.ui.control.TextArea
        FamilyNameEditField             matlab.ui.control.EditField
        FamilyNameEditFieldLabel        matlab.ui.control.Label
        GivenNameEditField              matlab.ui.control.EditField
        GivenNameEditFieldLabel         matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        AuthorEmailEditField            matlab.ui.control.EditField
        AuthorEmailEditFieldLabel       matlab.ui.control.Label
        AdditionalDetailsTextArea       matlab.ui.control.TextArea
        AdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        SelectanAuthorandfillintheirinfoListBox  matlab.ui.control.ListBox
        SelectanAuthorandfillintheirinfoListBoxLabel  matlab.ui.control.Label
        NumberofAuthorsEditField        matlab.ui.control.EditField
        NumberofAuthorsEditFieldLabel   matlab.ui.control.Label
        AddButton                       matlab.ui.control.Button
        AuthorRoleLabel                 matlab.ui.control.Label
        SaveButton_2                    matlab.ui.control.Button
        AuthorRoleTree                  matlab.ui.container.CheckBoxTree
        FirstAuthorNode                 matlab.ui.container.TreeNode
        CustodianNode                   matlab.ui.container.TreeNode
        CorrespondingNode               matlab.ui.container.TreeNode
        Tab3                            matlab.ui.container.Tab
        ErrorMessage                    matlab.ui.control.TextArea
        EmptyTechEmployedWarning        matlab.ui.control.Image
        EmptyExpApproachWarning         matlab.ui.control.Image
        EmptyVersionIdentifierWarning   matlab.ui.control.Image
        EmptyVersionInnovationWarning   matlab.ui.control.Image
        EmptyDataTypeWarning            matlab.ui.control.Image
        EmptyReleaseDateWarning         matlab.ui.control.Image
        EmptyFullDocumentationWarning   matlab.ui.control.Image
        ShortNameEditFieldLabel         matlab.ui.control.Label
        FullDocumentationEditFieldLabel  matlab.ui.control.Label
        ReleaseDateDatePickerLabel      matlab.ui.control.Label
        VersionInnovationEditField      matlab.ui.control.EditField
        VersionInnovationEditFieldLabel  matlab.ui.control.Label
        VersionIdentifierEditField      matlab.ui.control.EditField
        VersionIdentifierEditFieldLabel  matlab.ui.control.Label
        TechniquesEmployedLabel         matlab.ui.control.Label
        TechTree                        matlab.ui.container.CheckBoxTree
        Technique1Node                  matlab.ui.container.TreeNode
        ImmortalNode                    matlab.ui.container.TreeNode
        MortalNode                      matlab.ui.container.TreeNode
        InbetweenNode                   matlab.ui.container.TreeNode
        Technique2Node                  matlab.ui.container.TreeNode
        Node_2                          matlab.ui.container.TreeNode
        ExperimentalApproachLabel       matlab.ui.control.Label
        ExpTree                         matlab.ui.container.CheckBoxTree
        Approach1Node                   matlab.ui.container.TreeNode
        SubApp1Node                     matlab.ui.container.TreeNode
        SubApp2Node                     matlab.ui.container.TreeNode
        SubApp3Node                     matlab.ui.container.TreeNode
        Approach2Node                   matlab.ui.container.TreeNode
        SubApp2Node_2                   matlab.ui.container.TreeNode
        DataTypeLabel                   matlab.ui.control.Label
        CheckboxList                    wt.CheckboxList
        SaveButton_3                    matlab.ui.control.Button
        SUBMITButton                    matlab.ui.control.Button
        ShortNameEditField              matlab.ui.control.EditField
        FullDocumentationEditField      matlab.ui.control.EditField
        ReleaseDateDatePicker           matlab.ui.control.DatePicker
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, S, metadata_file_name)

            % Get the screen size
            screenSize = get(0, 'ScreenSize');

            % Set the Position property of UIFigure to cover the entire screen
            app.UIFigure.Position = [1, 1, screenSize(3) / 2, screenSize(4) / 2];
            % drawnow;
            % app.UIFigure.WindowState = 'maximized';
            % figureHandle = app.UIFigure;
            % set(findall(figureHandle, '-property', 'Units' ), 'Units', 'Normalized' )
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.S = S;
            ud.metadata_file_name = metadata_file_name;
            ud.fileSelectorInput = {};
            ud.licenseInput = {};
            ud.datasetBranchTitleInput = {};
            ud.abstractInput = {};
            ud.commentsInput = {};
            ud.numAuthors = 0;
            ud.institutes = {};
            ud.givenName = {};
            ud.familyName = {};
            ud.authorEmail = {};
            ud.digitalIdentifier = {};
            ud.additionalDetails= {};
            ud.authorRole= {};
            ud.relatedPublications = {};
            ud.authorArray = {};
            ud.dataType = {};
            ud.fullDocumentation = {};
            ud.dataType = {};
            ud.releaseDate = {};
            ud.techApproach = {};
            ud.expApproach = {};
            ud.versionInnovation = {};
            set(fig,'UserData',ud);
        end

        % Callback function
        function Example(app, event)
            
        end

        % Callback function
        function ButtonPushedFcn(app, event)


        end

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            % fileSelectorInput = app.FileSelector.Value;
            % LicenseInput = app.LicenseDropDown.Value;
            % datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            % abstractInput = app.AbstractTextArea.Value;
            % commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            fig = app.UIFigure;
            ud = fig.UserData; 
            ud.fileSelectorInput = app.FileSelector.Value;
            ud.licenseInput = app.LicenseDropDown.Value;
            ud.datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            ud.abstractInput = app.AbstractTextArea.Value;
            ud.commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            set(fig,'UserData',ud);
            % data = struct('funding', fileSelectorInput, 'License', LicenseInput,'datasetBranchTitle', datasetBranchTitleInput, 'abstract',abstractInput, 'comments', commentsInput);
            ndi.database.fun.dataset_metadata('S', ud.S, 'metadata_file_name', ud.metadata_file_name, 'steps', 1, 'action', 'save', 'data', ud);
        end

        % Button pushed function: AddAuthorButton
        function AddAuthorButtonPushedFcn(app, event)
            app.EmptyAuthorWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            
            fig = app.UIFigure;
            ud = fig.UserData;
            disp(1)
            if (isfield(ud, "numAuthors") && ud.numAuthors > 0)
                ud.numAuthors = ud.numAuthors + 1;
                ud.institutes(end+1) = {''};
                ud.givenName(end+1) = {''};
                ud.familyName(end+1) = {''};
                ud.authorEmail(end+1) = {''};
                ud.digitalIdentifier(end+1) = {''};
                ud.additionalDetails(end+1) = {''};
                ud.authorRole(end+1) = {[]};
                ud.relatedPublications(end+1) = {{}};
                ud.authorArray(end+1) = {'New Author'};
            else
                ud.numAuthors = 1;
                ud.institutes = {''};
                ud.givenName = {''};
                ud.familyName = {''};
                ud.authorEmail = {''};
                ud.digitalIdentifier = {''};
                ud.additionalDetails= {''};
                ud.authorRole= {[]};
                ud.relatedPublications = {{}};
                ud.authorArray = {'New Author'};
            end
            set(fig,'UserData',ud);
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{end};
        end

        % Value changing function: NumberofAuthorsEditField
        function NumberofAuthorsValueChanging(app, event)
            % value = app.NumberofAuthorsEditField.Value;
            % disp("detect user input");
            % numAuthors = str2double(value);
            % 
            % institutes = cell(1, numAuthors);
            % givenName = cell(1, numAuthors);
            % familyName = cell(1, numAuthors);
            % authorEmail = cell(1, numAuthors);
            % digitalIdentifier = cell(1, numAuthors);
            % additionalDetails = cell(1, numAuthors);
            % authorRole = cell(1, numAuthors);
            % relatedPublications = cell(1, numAuthors);
            % authorArray = cell(1, numAuthors);
            % 
            % for i = 1:numAuthors
            %     institutes{i} = "";
            %     givenName{i} = "";
            %     familyName{i} = "";
            %     authorEmail{i} = "";
            %     digitalIdentifier{i} = "";
            %     additionalDetails{i} = "";
            %     authorRole{i} = [];
            %     relatedPublications{i} = {};
            %     authorArray{i} = sprintf('Author %d', i); 
            % end
            % 
            % fig = app.UIFigure;
            % ud = fig.UserData;
            % ud.numAuthors = numAuthors;
            % ud.institutes = institutes;
            % ud.givenName = givenName;
            % ud.familyName = familyName;
            % ud.authorEmail = authorEmail;
            % ud.digitalIdentifier = digitalIdentifier;
            % ud.additionalDetails = additionalDetails;
            % ud.authorRole = authorRole;
            % ud.relatedPublications = relatedPublications;
            % ud.authorArray = authorArray;
            % set(fig,'UserData',ud);
            % app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            % 
            % 
        end

        % Clicked callback: SelectanAuthorandfillintheirinfoListBox
        function SelectAuthorClickedFcn(app, event)
            item = event.InteractionInformation.Item;
            fig = app.UIFigure;
            ud = fig.UserData;
            app.InstitutesAffiliationsTextArea.Value = ud.institutes{item};
            app.GivenNameEditField.Value = ud.givenName{item};
            app.FamilyNameEditField.Value = ud.familyName{item};
            app.AuthorEmailEditField.Value = ud.authorEmail{item};
            app.DigitalIdentifierEditField.Value = ud.digitalIdentifier{item};
            app.AdditionalDetailsTextArea.Value = ud.additionalDetails{item};
            app.AuthorRoleTree.CheckedNodes = ud.authorRole{item};
            app.UITable.Data = ud.relatedPublications{item};
        end

        % Value changing function: GivenNameEditField
        function GivenNameValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.givenName{authorIdx} = changingValue;
            familyName = ud.familyName{authorIdx};
            ud.authorArray{authorIdx} = sprintf('%s %s',changingValue, familyName);
            set(fig,'UserData',ud);
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{authorIdx};
        end

        % Value changing function: FamilyNameEditField
        function FamilyNameValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.familyName{authorIdx} = changingValue;
            givenName = ud.givenName{authorIdx};
            ud.authorArray{authorIdx} = sprintf('%s %s',givenName, changingValue);
            set(fig,'UserData',ud);
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{authorIdx};
        end

        % Value changing function: AuthorEmailEditField
        function AuthorEmailValueChangingFcn(app, event)
            app.EmptyEmailWarning.Visible = "off";
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.authorEmail{authorIdx} = changingValue;
            disp(ud.authorEmail{authorIdx})
            set(fig,'UserData',ud);
        end

        % Callback function: AuthorRoleTree
        function AuthorTreeCheckednodesChangedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.AuthorRoleTree;
            checkedNodes = cbt.CheckedNodes;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            ud.authorRole{authorIdx} = checkedNodes;
            set(fig,'UserData',ud);
        end

        % Value changing function: DigitalIdentifierEditField
        function DigitalIdentifierValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.digitalIdentifier{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Value changing function: AdditionalDetailsTextArea
        function AdditionalDetailsValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.additionalDetails{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Cell edit callback: UITable
        function UITableCellEditFcn(app, event)
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.relatedPublications{authorIdx} = app.UITable.Data;
            set(fig,'UserData',ud);
        end

        % Button pushed function: SaveButton_2
        function SaveButton_2PushedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ndi.database.fun.dataset_metadata('S', ud.S, "metadata_file_name", ud.metadata_file_name, 'steps', 2, 'action', 'save', 'data', ud);
        end

        % Value changing function: InstitutesAffiliationsTextArea
        function InstitutesAffiliationsValueChangingFcn(app, event)
            app.EmptyInstituteWarning.Visible = "off";
            changingValue = event.Value;
            disp(changingValue)
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.institutes{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Button pushed function: AddButton
        function AddButtonPushedFcn(app, event)
            currentData = app.UITable.Data;
            newRow = {'', ''};
            currentData = [currentData; newRow];
            app.UITable.Data = currentData;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.relatedPublications{authorIdx} = currentData;
            set(fig,'UserData',ud);
        end

        % Value changing function: FullDocumentationEditField
        function FullDocumentationValueChangingFcn(app, event)
            app.EmptyFullDocumentationWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.fullDocumentation = changingValue;
            set(fig,'UserData',ud);
        end

        % Value changed function: ReleaseDateDatePicker
        function ReleaseDateValueChangedFcn(app, event)
            app.EmptyReleaseDateWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            value = app.ReleaseDateDatePicker.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.releaseDate = value;
            set(fig,'UserData',ud);
        end

        % Value changing function: VersionInnovationEditField
        function VersionInnovationValueChangingFcn(app, event)
            app.EmptyVersionInnovationWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.versionInnovation = changingValue;
            set(fig,'UserData',ud);
        end

        % Callback function: CheckboxList
        function CheckboxListValueChangedFcn(app, event)
            app.EmptyDataTypeWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            value = app.CheckboxList.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.dataType = value;
            set(fig,'UserData',ud);
        end

        % Button pushed function: SUBMITButton
        function SubmitButtonPushedFcn(app, event)
            res = ndi.database.fun.dataset_metadata('action', 'check', 'app', app);
            submit = res.submit;
            errorStep = res.errorStep;
            if (submit)
                ndi.database.fun.dataset_metadata('steps', 3, 'action', 'submit', 'data', ud);
            else
                errorStepStr = "";
                for i = 1:numel(errorStep)
                    if errorStep{i} == i
                        errorStepStr  = errorStepStr + num2str(i) + " ";
                    end
                                      
                end
                errmsg = sprintf('Errors occured in step %s. Please fix them', errorStepStr);
                app.ErrorMessage.Value = errmsg;
                app.ErrorMessage.Visible = "on";
            end
        

        end

        % Button pushed function: DeleteButton
        function DeleteButtonPushedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            if (isfield(ud, "numAuthors") && ud.numAuthors > 0)
                ud.numAuthors = ud.numAuthors - 1;
                listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
                authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
                ud.institutes(authorIdx:authorIdx) = [];
                ud.givenName(authorIdx:authorIdx) = [];
                ud.familyName(authorIdx:authorIdx) = [];
                ud.authorEmail(authorIdx:authorIdx) = [];
                ud.digitalIdentifier(authorIdx:authorIdx) = [];
                ud.additionalDetails(authorIdx:authorIdx) = [];
                ud.authorRole(authorIdx:authorIdx) = [];
                ud.relatedPublications(authorIdx:authorIdx) = [];
                ud.authorArray(authorIdx:authorIdx) = [];
                set(fig,'UserData',ud);
                app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            end
        end

        % Callback function: ExpTree
        function ExpTreeCheckednodesChangedFcn(app, event)
            app.EmptyExpApproachWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.ExpTree;
            checkedNodes = cbt.CheckedNodes;
            ud.expApproach = checkedNodes;
            set(fig,'UserData',ud);
        end

        % Callback function: TechTree
        function TechTreeCheckednodesChangedFcn(app, event)
            app.EmptyTechEmployedWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.TechTree;
            checkedNodes = cbt.CheckedNodes;
            ud.techApproach = checkedNodes;
            set(fig,'UserData',ud);
        end

        % Value changing function: AbstractTextArea
        function AbstractValueChangingFcn(app, event)
            app.EmptyAbstractWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.abstractInput = changingValue;
            set(fig,'UserData',ud);
        end

        % Value changed function: LicenseDropDown
        function LicenseValueChangedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.licenseInput = app.LicenseDropDown.Value;
            set(fig,'UserData',ud);
        end

        % Value changing function: DatasetBranchTitleEditField
        function DatasetBranchValueChangingFcn(app, event)
            app.EmptyBranchTitleWarning.Visible = "off";
            app.ErrorMessage.Visible = "off";
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.datasetBranchTitleInput = app.DatasetBranchTitleEditField.Value;
            set(fig,'UserData',ud);
        end

        % Value changing function: CommentsAdditionalDetailsTextArea
        function CommentsValueChangingFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.commentsInput = app.CommentsAdditionalDetailsTextArea.Value;
            set(fig,'UserData',ud);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.Label = uilabel(app.Tab);
            app.Label.Text = {'Welcome to the NDI Cloud''s Upload Tool!'; ''; 'We''re excited to have you here. This is your upload form, where you can effortlessly share your data with us. Over the next few pages, we''ll guide you through the process of ingesting your valuable data. Our goal is to make this as seamless as possible, ensuring that your information is accurately processed and ready for analysis. If you ever need assistance with any of the form elements, simply hover over the respective item to access helpful information. For any further queries, shoot us an email at info@walthamdatascience.com and we''ll get right back to you! Thank you for choosing our app to help you manage your data. Let''s get started on this journey together!'};
            app.DatasetIdentifierEditFieldLabel = uilabel(app.Tab);
            app.DatasetIdentifierEditFieldLabel.Text = 'Dataset Identifier';
            app.DatasetIdentifierEditField = uieditfield(app.Tab, 'text');
            app.DatasetIdentifierEditField.InputType = 'alphanumerics';
            app.DatasetIdentifierEditField.Tooltip = {'Auto-generated digital nametag for your dataset, no input required'};
            app.DatasetIdentifierEditField.Value = 'GENERATE';
            app.FileSelector = wt.FileSelector(app.Tab);
            app.FileSelector.Tooltip = "Accepted formats: PDF, DOCX, XML";
            app.FundingLabel = uilabel(app.Tab);
            app.FundingLabel.Text = 'Funding';
            app.LicenseDropDownLabel = uilabel(app.Tab);
            app.LicenseDropDownLabel.Text = 'License';
            app.LicenseDropDown = uidropdown(app.Tab);
            app.LicenseDropDown.ValueChangedFcn = createCallbackFcn(app, @LicenseValueChangedFcn, true);
            app.LicenseDropDown.Tooltip = {'Choose from one of our approved licenses'};
            app.DatasetBranchTitleEditFieldLabel = uilabel(app.Tab);
            app.DatasetBranchTitleEditFieldLabel.Text = 'Dataset Branch Title';
            app.DatasetBranchTitleEditField = uieditfield(app.Tab, 'text');
            app.DatasetBranchTitleEditField.ValueChangingFcn = createCallbackFcn(app, @DatasetBranchValueChangingFcn, true);
            app.DatasetBranchTitleEditField.Tooltip = {'Assign a name to this branch'};
            app.AbstractTextAreaLabel = uilabel(app.Tab);
            app.AbstractTextAreaLabel.Text = 'Abstract';
            app.AbstractTextArea = uitextarea(app.Tab);
            app.AbstractTextArea.ValueChangingFcn = createCallbackFcn(app, @AbstractValueChangingFcn, true);
            app.CommentsAdditionalDetailsTextAreaLabel = uilabel(app.Tab);
            app.CommentsAdditionalDetailsTextAreaLabel.Text = {'Comments/ '; 'Additional Details'};
            app.CommentsAdditionalDetailsTextArea = uitextarea(app.Tab);
            app.CommentsAdditionalDetailsTextArea.ValueChangingFcn = createCallbackFcn(app, @CommentsValueChangingFcn, true);
            app.CommentsAdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about your upload? Note: This field is not public facing.'};
            app.SaveButton = uibutton(app.Tab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.EmptyBranchTitleWarning = uiimage(app.Tab);
            app.EmptyBranchTitleWarning.Visible = 'off';
            app.EmptyBranchTitleWarning.Tooltip = {''};
            app.EmptyBranchTitleWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyAbstractWarning = uiimage(app.Tab);
            app.EmptyAbstractWarning.Visible = 'off';
            app.EmptyAbstractWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.AuthorRoleTree = uitree(app.Tab2, 'checkbox');
            app.AuthorRoleTree.Tooltip = {'An author can have multiple roles'};
            app.FirstAuthorNode = uitreenode(app.AuthorRoleTree);
            app.FirstAuthorNode.Text = 'First Author';
            app.CustodianNode = uitreenode(app.AuthorRoleTree);
            app.CustodianNode.Text = 'Custodian';
            app.CorrespondingNode = uitreenode(app.AuthorRoleTree);
            app.CorrespondingNode.Text = 'Corresponding';
            % Assign Checked Nodes
            app.AuthorRoleTree.CheckedNodesChangedFcn = createCallbackFcn(app, @AuthorTreeCheckednodesChangedFcn, true);
            app.SaveButton_2 = uibutton(app.Tab2, 'push');
            app.SaveButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_2PushedFcn, true);
            app.SaveButton_2.Text = 'Save';
            app.AuthorRoleLabel = uilabel(app.Tab2);
            app.AuthorRoleLabel.Text = 'Author Role';
            app.AddButton = uibutton(app.Tab2, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushedFcn, true);
            app.AddButton.Text = 'Add';
            app.NumberofAuthorsEditFieldLabel = uilabel(app.Tab2);
            app.NumberofAuthorsEditFieldLabel.Text = 'Number of Authors';
            app.NumberofAuthorsEditField = uieditfield(app.Tab2, 'text');
            app.NumberofAuthorsEditField.ValueChangingFcn = createCallbackFcn(app, @NumberofAuthorsValueChanging, true);
            app.NumberofAuthorsEditField.Tooltip = {'Enter the number(digits) only'};
            app.SelectanAuthorandfillintheirinfoListBoxLabel = uilabel(app.Tab2);
            app.SelectanAuthorandfillintheirinfoListBoxLabel.Text = {'Select an Author'; 'and fill in their info'};
            app.SelectanAuthorandfillintheirinfoListBox = uilistbox(app.Tab2);
            app.SelectanAuthorandfillintheirinfoListBox.Items = {};
            app.SelectanAuthorandfillintheirinfoListBox.ClickedFcn = createCallbackFcn(app, @SelectAuthorClickedFcn, true);
            app.SelectanAuthorandfillintheirinfoListBox.Value = {};
            app.AdditionalDetailsTextAreaLabel = uilabel(app.Tab2);
            app.AdditionalDetailsTextAreaLabel.Text = {'Additional'; 'Details'};
            app.AdditionalDetailsTextArea = uitextarea(app.Tab2);
            app.AdditionalDetailsTextArea.ValueChangingFcn = createCallbackFcn(app, @AdditionalDetailsValueChangingFcn, true);
            app.AdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about this author? Note: This field is not public facing.'};
            app.AuthorEmailEditFieldLabel = uilabel(app.Tab2);
            app.AuthorEmailEditFieldLabel.Text = 'Author Email';
            app.AuthorEmailEditField = uieditfield(app.Tab2, 'text');
            app.AuthorEmailEditField.ValueChangingFcn = createCallbackFcn(app, @AuthorEmailValueChangingFcn, true);
            app.DigitalIdentifierEditFieldLabel = uilabel(app.Tab2);
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.Tab2, 'text');
            app.DigitalIdentifierEditField.ValueChangingFcn = createCallbackFcn(app, @DigitalIdentifierValueChangingFcn, true);
            app.DigitalIdentifierEditField.Tooltip = {'ORC ID'};
            app.GivenNameEditFieldLabel = uilabel(app.Tab2);
            app.GivenNameEditFieldLabel.Text = 'Given Name';
            app.GivenNameEditField = uieditfield(app.Tab2, 'text');
            app.GivenNameEditField.ValueChangingFcn = createCallbackFcn(app, @GivenNameValueChangingFcn, true);
            app.FamilyNameEditFieldLabel = uilabel(app.Tab2);
            app.FamilyNameEditFieldLabel.Text = 'Family Name';
            app.FamilyNameEditField = uieditfield(app.Tab2, 'text');
            app.FamilyNameEditField.ValueChangingFcn = createCallbackFcn(app, @FamilyNameValueChangingFcn, true);
            app.RelatedPublicationsTextArea = uitextarea(app.Tab2);
            app.UITable = uitable(app.Tab2);
            app.UITable.ColumnName = {'Publication'; 'DOI'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEditFcn, true);
            app.RelatedPublicationsTextAreaLabel = uilabel(app.Tab2);
            app.RelatedPublicationsTextAreaLabel.Text = {'Related'; 'Publications'};
            app.InstitutesAffiliationsTextAreaLabel = uilabel(app.Tab2);
            app.InstitutesAffiliationsTextAreaLabel.Text = 'Institutes/ Affiliations';
            app.InstitutesAffiliationsTextArea = uitextarea(app.Tab2);
            app.InstitutesAffiliationsTextArea.ValueChangingFcn = createCallbackFcn(app, @InstitutesAffiliationsValueChangingFcn, true);
            app.NumAuthorWarning = uiimage(app.Tab2);
            app.NumAuthorWarning.Visible = 'off';
            app.NumAuthorWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyGivenNameWarning = uiimage(app.Tab2);
            app.EmptyGivenNameWarning.Visible = 'off';
            app.EmptyGivenNameWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyFamilyNameWarning = uiimage(app.Tab2);
            app.EmptyFamilyNameWarning.Visible = 'off';
            app.EmptyFamilyNameWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyEmailWarning = uiimage(app.Tab2);
            app.EmptyEmailWarning.Visible = 'off';
            app.EmptyEmailWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyDigitalIdentifierWarning = uiimage(app.Tab2);
            app.EmptyDigitalIdentifierWarning.Visible = 'off';
            app.EmptyDigitalIdentifierWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyInstituteWarning = uiimage(app.Tab2);
            app.EmptyInstituteWarning.Visible = 'off';
            app.EmptyInstituteWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.AddAuthorButton = uibutton(app.Tab2, 'push');
            app.AddAuthorButton.ButtonPushedFcn = createCallbackFcn(app, @AddAuthorButtonPushedFcn, true);
            app.AddAuthorButton.Text = 'Add';
            app.DeleteButton = uibutton(app.Tab2, 'push');
            app.DeleteButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteButtonPushedFcn, true);
            app.DeleteButton.Text = 'Delete';
            app.EmptyAuthorWarning = uiimage(app.Tab2);
            app.EmptyAuthorWarning.Visible = 'off';
            app.EmptyAuthorWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyRelatedPublicationsWarning = uiimage(app.Tab2);
            app.EmptyRelatedPublicationsWarning.Visible = 'off';
            app.EmptyRelatedPublicationsWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.ReleaseDateDatePicker = uidatepicker(app.Tab3);
            app.ReleaseDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ReleaseDateValueChangedFcn, true);
            app.FullDocumentationEditField = uieditfield(app.Tab3, 'text');
            app.FullDocumentationEditField.ValueChangingFcn = createCallbackFcn(app, @FullDocumentationValueChangingFcn, true);
            app.FullDocumentationEditField.Tooltip = {'Paste the DOI of the research paper here'};
            app.ShortNameEditField = uieditfield(app.Tab3, 'text');
            app.ShortNameEditField.Value = 'GENERATE';
            app.SUBMITButton = uibutton(app.Tab3, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushedFcn, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.SaveButton_3 = uibutton(app.Tab3, 'push');
            app.SaveButton_3.Text = 'Save';
            app.CheckboxList = wt.CheckboxList(app.Tab3);
            app.CheckboxList.Value = [false;true;true;true;true;true];
            app.CheckboxList.ShowSelectAll = 'on';
            app.CheckboxList.ValueChangedFcn = createCallbackFcn(app, @CheckboxListValueChangedFcn, true);
            app.CheckboxList.Tooltip = "Select Data Type(s)";
            app.DataTypeLabel = uilabel(app.Tab3);
            app.DataTypeLabel.Text = 'Data Type';
            app.ExpTree = uitree(app.Tab3, 'checkbox');
            app.ExpTree.Tooltip = {'Select approaches(and sub approaches) used in the study'};
            app.Approach1Node = uitreenode(app.ExpTree);
            app.Approach1Node.Text = 'Approach 1';
            app.SubApp1Node = uitreenode(app.Approach1Node);
            app.SubApp1Node.Text = 'SubApp1';
            app.SubApp2Node = uitreenode(app.Approach1Node);
            app.SubApp2Node.Text = 'SubApp2';
            app.SubApp3Node = uitreenode(app.Approach1Node);
            app.SubApp3Node.Text = 'SubApp3';
            app.Approach2Node = uitreenode(app.ExpTree);
            app.Approach2Node.Text = 'Approach2';
            app.SubApp2Node_2 = uitreenode(app.Approach2Node);
            app.SubApp2Node_2.Text = 'SubApp2';
            % Assign Checked Nodes
            app.ExpTree.CheckedNodesChangedFcn = createCallbackFcn(app, @ExpTreeCheckednodesChangedFcn, true);
            app.ExperimentalApproachLabel = uilabel(app.Tab3);
            app.ExperimentalApproachLabel.Text = 'Experimental Approach';
            app.TechTree = uitree(app.Tab3, 'checkbox');
            app.Technique1Node = uitreenode(app.TechTree);
            app.Technique1Node.Text = 'Technique 1';
            app.ImmortalNode = uitreenode(app.Technique1Node);
            app.ImmortalNode.Text = 'Immortal';
            app.MortalNode = uitreenode(app.Technique1Node);
            app.MortalNode.Text = 'Mortal';
            app.InbetweenNode = uitreenode(app.Technique1Node);
            app.InbetweenNode.Text = 'Inbetween';
            app.Technique2Node = uitreenode(app.TechTree);
            app.Technique2Node.Text = 'Technique 2';
            app.Node_2 = uitreenode(app.Technique2Node);
            app.Node_2.Text = '21';
            % Assign Checked Nodes
            app.TechTree.CheckedNodesChangedFcn = createCallbackFcn(app, @TechTreeCheckednodesChangedFcn, true);
            app.TechniquesEmployedLabel = uilabel(app.Tab3);
            app.TechniquesEmployedLabel.Text = 'Techniques Employed ';
            app.VersionIdentifierEditFieldLabel = uilabel(app.Tab3);
            app.VersionIdentifierEditFieldLabel.Text = 'Version Identifier';
            app.VersionIdentifierEditField = uieditfield(app.Tab3, 'text');
            app.VersionIdentifierEditField.Tooltip = {'Auto-generated by our system, no input required'};
            app.VersionIdentifierEditField.Value = 'GENERATE';
            app.VersionInnovationEditFieldLabel = uilabel(app.Tab3);
            app.VersionInnovationEditFieldLabel.Text = 'Version Innovation';
            app.VersionInnovationEditField = uieditfield(app.Tab3, 'text');
            app.VersionInnovationEditField.ValueChangingFcn = createCallbackFcn(app, @VersionInnovationValueChangingFcn, true);
            app.VersionInnovationEditField.Tooltip = {'If multiple, separate by comma '};
            app.ReleaseDateDatePickerLabel = uilabel(app.Tab3);
            app.ReleaseDateDatePickerLabel.Text = 'Release Date';
            app.FullDocumentationEditFieldLabel = uilabel(app.Tab3);
            app.FullDocumentationEditFieldLabel.Text = 'Full Documentation';
            app.ShortNameEditFieldLabel = uilabel(app.Tab3);
            app.ShortNameEditFieldLabel.Text = 'Short Name';
            app.EmptyFullDocumentationWarning = uiimage(app.Tab3);
            app.EmptyFullDocumentationWarning.Visible = 'off';
            app.EmptyFullDocumentationWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyReleaseDateWarning = uiimage(app.Tab3);
            app.EmptyReleaseDateWarning.Visible = 'off';
            app.EmptyReleaseDateWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyDataTypeWarning = uiimage(app.Tab3);
            app.EmptyDataTypeWarning.Visible = 'off';
            app.EmptyDataTypeWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyVersionInnovationWarning = uiimage(app.Tab3);
            app.EmptyVersionInnovationWarning.Visible = 'off';
            app.EmptyVersionInnovationWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyVersionIdentifierWarning = uiimage(app.Tab3);
            app.EmptyVersionIdentifierWarning.Visible = 'off';
            app.EmptyVersionIdentifierWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyExpApproachWarning = uiimage(app.Tab3);
            app.EmptyExpApproachWarning.Visible = 'off';
            app.EmptyExpApproachWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.EmptyTechEmployedWarning = uiimage(app.Tab3);
            app.EmptyTechEmployedWarning.Visible = 'off';
            app.EmptyTechEmployedWarning.ImageSource = 'attention-sign-or-warning-caution-exclamation-sign-danger-yellow-triangle-stock-illustration-free-vector.jpeg';
            app.ErrorMessage = uitextarea(app.Tab3);
            app.ErrorMessage.Visible = 'off';
            app.DATASETESSENTIALSLabel = uilabel(app.UIFigure);
            app.DATASETESSENTIALSLabel.Text = 'DATASET ESSENTIALS';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end