classdef app2AltView < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveButton                  matlab.ui.control.Button
        TaskStatusTable             wt.TaskStatusTable
        VersionInnovationEditField  matlab.ui.control.EditField
        VersionInnovationEditFieldLabel  matlab.ui.control.Label
        VersionIdentifierEditField  matlab.ui.control.EditField
        VersionIdentifierEditFieldLabel  matlab.ui.control.Label
        SUBMITButton                matlab.ui.control.Button
        TechniquesEmployedLabel     matlab.ui.control.Label
        Tree2                       matlab.ui.container.CheckBoxTree
        Technique1Node              matlab.ui.container.TreeNode
        ImmortalNode                matlab.ui.container.TreeNode
        MortalNode                  matlab.ui.container.TreeNode
        InbetweenNode               matlab.ui.container.TreeNode
        Technique2Node              matlab.ui.container.TreeNode
        Node_2                      matlab.ui.container.TreeNode
        ShortNameEditField          matlab.ui.control.EditField
        ShortNameEditFieldLabel     matlab.ui.control.Label
        FullDocumentationEditField  matlab.ui.control.EditField
        FullDocumentationEditFieldLabel  matlab.ui.control.Label
        ReleaseDateDatePicker       matlab.ui.control.DatePicker
        ReleaseDateDatePickerLabel  matlab.ui.control.Label
        ExperimentalApproachLabel   matlab.ui.control.Label
        Tree                        matlab.ui.container.CheckBoxTree
        Approach1Node               matlab.ui.container.TreeNode
        SubApp1Node                 matlab.ui.container.TreeNode
        SubApp2Node                 matlab.ui.container.TreeNode
        SubApp3Node                 matlab.ui.container.TreeNode
        Approach2Node               matlab.ui.container.TreeNode
        SubApp2Node_2               matlab.ui.container.TreeNode
        DataTypeLabel               matlab.ui.control.Label
        CheckboxList                wt.CheckboxList
        DATASETDETAILSLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ndi.database.fun.dataset_metadata('steps', 3, 'action', 'save', 'data', ud);
        end

        % Value changing function: FullDocumentationEditField
        function FullDocumentationValueChangingFcn(app, event)
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.fullDocumentation = changingValue;
            set(fig,'UserData',ud);
        end

        % Value changed function: ReleaseDateDatePicker
        function ReleaseDateValueChangedFcn(app, event)
            value = app.ReleaseDateDatePicker.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.releaseDate = value;
            set(fig,'UserData',ud);
        end

        % Value changing function: VersionInnovationEditField
        function VersionInnovationValueChangingFcn(app, event)
            changingValue = event.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.versionInnovation = changingValue;
            set(fig,'UserData',ud);
        end

        % Callback function: CheckboxList
        function CheckboxListValueChangedFcn(app, event)
            value = app.CheckboxList.Value;
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.dataType = value;
            set(fig,'UserData',ud);
        end

        % Button pushed function: SUBMITButton
        function SubmitButtonPushedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ndi.database.fun.dataset_metadata('steps', 3, 'action', 'submit', 'data', ud);
        end

        % Callback function: Tree
        function ExpTreeCheckednodesChangedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.Tree;
            checkedNodes = cbt.CheckedNodes;
            disp("expApproach checked nodes")
            disp(checkedNodes)
            ud.expApproach = checkedNodes;
            set(fig,'UserData',ud);
        end

        % Callback function: Tree2
        function TechTreeCheckednodesChangedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.Tree;
            checkedNodes = cbt.CheckedNodes;
            ud.techApproach = checkedNodes;
            set(fig,'UserData',ud);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.DATASETDETAILSLabel = uilabel(app.UIFigure);
            app.DATASETDETAILSLabel.Text = 'DATASET DETAILS';
            app.CheckboxList = wt.CheckboxList(app.UIFigure);
            app.CheckboxList.Value = [false;true;true;true;true;true];
            app.CheckboxList.ShowSelectAll = 'on';
            app.CheckboxList.ValueChangedFcn = createCallbackFcn(app, @CheckboxListValueChangedFcn, true);
            app.CheckboxList.Tooltip = "Select Data Type(s)";
            app.DataTypeLabel = uilabel(app.UIFigure);
            app.DataTypeLabel.Text = 'Data Type';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Tooltip = {'Select approaches(and sub approaches) used in the study'};
            app.Approach1Node = uitreenode(app.Tree);
            app.Approach1Node.Text = 'Approach 1';
            app.SubApp1Node = uitreenode(app.Approach1Node);
            app.SubApp1Node.Text = 'SubApp1';
            app.SubApp2Node = uitreenode(app.Approach1Node);
            app.SubApp2Node.Text = 'SubApp2';
            app.SubApp3Node = uitreenode(app.Approach1Node);
            app.SubApp3Node.Text = 'SubApp3';
            app.Approach2Node = uitreenode(app.Tree);
            app.Approach2Node.Text = 'Approach2';
            app.SubApp2Node_2 = uitreenode(app.Approach2Node);
            app.SubApp2Node_2.Text = 'SubApp2';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @ExpTreeCheckednodesChangedFcn, true);
            app.ExperimentalApproachLabel = uilabel(app.UIFigure);
            app.ExperimentalApproachLabel.Text = 'Experimental Approach';
            app.ReleaseDateDatePickerLabel = uilabel(app.UIFigure);
            app.ReleaseDateDatePickerLabel.Text = 'Release Date';
            app.ReleaseDateDatePicker = uidatepicker(app.UIFigure);
            app.ReleaseDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @ReleaseDateValueChangedFcn, true);
            app.FullDocumentationEditFieldLabel = uilabel(app.UIFigure);
            app.FullDocumentationEditFieldLabel.Text = 'Full Documentation';
            app.FullDocumentationEditField = uieditfield(app.UIFigure, 'text');
            app.FullDocumentationEditField.ValueChangingFcn = createCallbackFcn(app, @FullDocumentationValueChangingFcn, true);
            app.FullDocumentationEditField.Tooltip = {'Paste the DOI of the research paper here'};
            app.ShortNameEditFieldLabel = uilabel(app.UIFigure);
            app.ShortNameEditFieldLabel.Text = 'Short Name';
            app.ShortNameEditField = uieditfield(app.UIFigure, 'text');
            app.ShortNameEditField.Value = 'GENERATE';
            app.Tree2 = uitree(app.UIFigure, 'checkbox');
            app.Technique1Node = uitreenode(app.Tree2);
            app.Technique1Node.Text = 'Technique 1';
            app.ImmortalNode = uitreenode(app.Technique1Node);
            app.ImmortalNode.Text = 'Immortal';
            app.MortalNode = uitreenode(app.Technique1Node);
            app.MortalNode.Text = 'Mortal';
            app.InbetweenNode = uitreenode(app.Technique1Node);
            app.InbetweenNode.Text = 'Inbetween';
            app.Technique2Node = uitreenode(app.Tree2);
            app.Technique2Node.Text = 'Technique 2';
            app.Node_2 = uitreenode(app.Technique2Node);
            app.Node_2.Text = '21';
            % Assign Checked Nodes
            app.Tree2.CheckedNodesChangedFcn = createCallbackFcn(app, @TechTreeCheckednodesChangedFcn, true);
            app.TechniquesEmployedLabel = uilabel(app.UIFigure);
            app.TechniquesEmployedLabel.Text = 'Techniques Employed ';
            app.SUBMITButton = uibutton(app.UIFigure, 'push');
            app.SUBMITButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushedFcn, true);
            app.SUBMITButton.Text = 'SUBMIT';
            app.VersionIdentifierEditFieldLabel = uilabel(app.UIFigure);
            app.VersionIdentifierEditFieldLabel.Text = 'Version Identifier';
            app.VersionIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.VersionIdentifierEditField.Tooltip = {'Auto-generated by our system, no input required'};
            app.VersionIdentifierEditField.Value = 'GENERATE';
            app.VersionInnovationEditFieldLabel = uilabel(app.UIFigure);
            app.VersionInnovationEditFieldLabel.Text = 'Version Innovation';
            app.VersionInnovationEditField = uieditfield(app.UIFigure, 'text');
            app.VersionInnovationEditField.ValueChangingFcn = createCallbackFcn(app, @VersionInnovationValueChangingFcn, true);
            app.VersionInnovationEditField.Tooltip = {'If multiple, separate by comma '};
            app.TaskStatusTable = wt.TaskStatusTable(app.UIFigure);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2AltView

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end