classdef app3v1_4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        AddButton                       matlab.ui.control.Button
        NextButton                      matlab.ui.control.Button
        InstitutesAffiliationsTextArea  matlab.ui.control.TextArea
        InstitutesAffiliationsTextAreaLabel  matlab.ui.control.Label
        AuthorRoleLabel                 matlab.ui.control.Label
        FamilyNameEditField             matlab.ui.control.EditField
        FamilyNameEditFieldLabel        matlab.ui.control.Label
        GivenNameEditField_2            matlab.ui.control.EditField
        GivenNameEditField_2Label       matlab.ui.control.Label
        DigitalIdentifierEditField      matlab.ui.control.EditField
        RelatedPublicationLabel         matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        RelatedPublicationsTextArea     matlab.ui.control.TextArea
        RelatedPublicationsTextAreaLabel  matlab.ui.control.Label
        AuthorEmailEditField            matlab.ui.control.EditField
        AuthorEmailLabel                matlab.ui.control.Label
        AdditionalDetailsTextArea       matlab.ui.control.TextArea
        AdditionalDetailsTextAreaLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        Tree                            matlab.ui.container.CheckBoxTree
        FirstAuthorNode                 matlab.ui.container.TreeNode
        CustodianNode                   matlab.ui.container.TreeNode
        CorrespondingNode               matlab.ui.container.TreeNode
        SelectanAuthorandfillintheirinfoListBox  matlab.ui.control.ListBox
        SelectanAuthorandfillintheirinfoListBoxLabel  matlab.ui.control.Label
        NumberofAuthorsEditField        matlab.ui.control.EditField
        NumberofAuthorsEditFieldLabel   matlab.ui.control.Label
        AUTHORDETAILSLabel              matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofAuthorsEditField
        function NumberofAuthorsValueChanged(app, event)
            value = app.NumberofAuthorsEditField.Value;
            % authorString = ndi.database.fun.dataset_metadata('steps', 3, 'action', 'initiate', 'numAuthors', value);
            disp("detect user input");
            numAuthors = str2double(value);

            institutes = cell(1, numAuthors);
            givenName = cell(1, numAuthors);
            familyName = cell(1, numAuthors);
            authorEmail = cell(1, numAuthors);
            digitalIdentifier = cell(1, numAuthors);
            additionalDetails = cell(1, numAuthors);
            authorRole = cell(1, numAuthors);
            relatedPublications = cell(1, numAuthors);
            authorArray = cell(1, numAuthors);

            for i = 1:numAuthors
                institutes{i} = "";
                givenName{i} = "";
                familyName{i} = "";
                authorEmail{i} = "";
                digitalIdentifier{i} = "";
                additionalDetails{i} = "";
                authorRole{i} = [];
                relatedPublications{i} = {};
                authorArray{i} = sprintf('Author %d', i); 
            end

            fig = app.UIFigure;
            ds.numAuthors = numAuthors;
            ds.institutes = institutes;
            ds.givenName = givenName;
            ds.familyName = familyName;
            ds.authorEmail = authorEmail;
            ds.digitalIdentifier = digitalIdentifier;
            ds.additionalDetails = additionalDetails;
            ds.authorRole = authorRole;
            ds.relatedPublications = relatedPublications;
            ds.authorArray = authorArray;
            fig.UserData = ds;
            ud = fig.UserData;
            if isstruct(ud)
                disp("data found");
            else
                disp("data not found");
            end
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
        end

        % Clicked callback: SelectanAuthorandfillintheirinfoListBox
        function SelectAuthorClickedFcn(app, event)
            item = event.InteractionInformation.Item;
            fig = app.UIFigure;
            ud = fig.UserData;
            % authorInfo = ndi.database.fun.dataset_metadata('steps', 3, 'action', 'retrieve', 'authorIdx', item);
            % disp(ud.givenName{item});
            app.InstitutesAffiliationsTextArea.Value = ud.institutes{item};
            app.GivenNameEditField_2.Value = ud.givenName{item};
            app.FamilyNameEditField.Value = ud.familyName{item};
            app.AuthorEmailEditField.Value = ud.authorEmail{item};
            app.DigitalIdentifierEditField.Value = ud.digitalIdentifier{item};
            app.AdditionalDetailsTextArea.Value = ud.additionalDetails{item};
            app.Tree.CheckedNodes = ud.authorRole{item};
            app.UITable.Data = ud.relatedPublications{item};
            
        end

        % Value changing function: GivenNameEditField_2
        function GivenNameValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            % authorIdx = num2str(selectedIndex);
            % givenName = ndi.database.fun.dataset_metadata('steps', 3, 'action', 'update given name', 'authorIdx', authorIdx, 'updatedGivenName', changingValue);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.givenName{authorIdx} = changingValue;
            familyName = ud.familyName{authorIdx};
            ud.authorArray{authorIdx} = sprintf('%s %s',changingValue, familyName);
            set(fig,'UserData',ud);
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{authorIdx};

        end

        % Value changing function: FamilyNameEditField
        function FamilyNameValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            % authorIdx = num2str(selectedIndex);
            % givenName = ndi.database.fun.dataset_metadata('steps', 3, 'action', 'update given name', 'authorIdx', authorIdx, 'updatedGivenName', changingValue);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.familyName{authorIdx} = changingValue;
            givenName = ud.givenName{authorIdx};
            ud.authorArray{authorIdx} = sprintf('%s %s',givenName, changingValue);
            set(fig,'UserData',ud);
            app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{authorIdx};
        end

        % Value changing function: AuthorEmailEditField
        function AuthorEmailValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.authorEmail{authorIdx} = changingValue;
            set(fig,'UserData',ud);
            % app.SelectanAuthorandfillintheirinfoListBox.Items = ud.authorArray;
            % app.SelectanAuthorandfillintheirinfoListBox.Value = ud.authorArray{authorIdx};
        end

        % Callback function: Tree
        function AuthorTreeCheckednodesChangedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            cbt = app.Tree;
            checkedNodes = cbt.CheckedNodes;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            ud.authorRole{authorIdx} = checkedNodes;
            set(fig,'UserData',ud);
            disp(ud.authorRole);
        end

        % Value changing function: DigitalIdentifierEditField
        function DigitalIdentifierValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.digitalIdentifier{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Value changing function: AdditionalDetailsTextArea
        function AdditionalDetailsValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.additionalDetails{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            % row = event.Indices(1);
            % col = event.Indices(2);
            % 
            % % Get the new data entered by the user
            % newData = event.NewData;
            % 
            % % You can process or log the user's input as needed
            % disp(['User edited cell at row ', num2str(row), ' and column ', num2str(col)]);
            % disp(['New data: ', newData]);
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.relatedPublications{authorIdx} = app.UITable.Data;
            set(fig,'UserData',ud);
            % Optionally, you can save the updated table data to fig.userData
            % fig = app.UIFigure;
            % fig.UserData.TableData = app.Table.Data;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushedFcn(app, event)
            fig = app.UIFigure;
            ud = fig.UserData;
            ndi.database.fun.dataset_metadata('steps', 2, 'action', 'save', 'data', ud);
        end

        % Value changing function: InstitutesAffiliationsTextArea
        function InstitutesAffiliationsValueChangingFcn(app, event)
            changingValue = event.Value;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.institutes{authorIdx} = changingValue;
            set(fig,'UserData',ud);
        end

        % Button pushed function: NextButton
        function NextButtonPushedFcn(app, event)

            ndi.database.fun.dataset_metadata('steps', 3, 'action', 'open');
        end

        % Button pushed function: AddButton
        function AddButtonPushedFcn(app, event)
            currentData = app.UITable.Data;
            newRow = {'', ''};
            currentData = [currentData; newRow];
            app.UITable.Data = currentData;
            listBoxItems = app.SelectanAuthorandfillintheirinfoListBox.Items;
            authorIdx = find(strcmpi(app.SelectanAuthorandfillintheirinfoListBox.Value, listBoxItems), 1);
            fig = app.UIFigure;
            ud = fig.UserData;
            ud.relatedPublications{authorIdx} = currentData;
            set(fig,'UserData',ud);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AUTHORDETAILSLabel = uilabel(app.UIFigure);
            app.AUTHORDETAILSLabel.Text = 'AUTHOR DETAILS';
            app.NumberofAuthorsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofAuthorsEditFieldLabel.Text = 'Number of Authors';
            app.NumberofAuthorsEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofAuthorsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofAuthorsValueChanged, true);
            app.NumberofAuthorsEditField.Tooltip = {'Enter the number(digits) only'};
            app.SelectanAuthorandfillintheirinfoListBoxLabel = uilabel(app.UIFigure);
            app.SelectanAuthorandfillintheirinfoListBoxLabel.Text = {'Select an Author'; 'and fill in their info'};
            app.SelectanAuthorandfillintheirinfoListBox = uilistbox(app.UIFigure);
            app.SelectanAuthorandfillintheirinfoListBox.Items = {'Author 1', 'Author 2', 'Author 3', 'Author 4'};
            app.SelectanAuthorandfillintheirinfoListBox.ClickedFcn = createCallbackFcn(app, @SelectAuthorClickedFcn, true);
            app.SelectanAuthorandfillintheirinfoListBox.Value = 'Author 1';
            app.Tree = uitree(app.UIFigure, 'checkbox');
            app.Tree.Tooltip = {'An author can have multiple roles'};
            app.FirstAuthorNode = uitreenode(app.Tree);
            app.FirstAuthorNode.Text = 'First Author';
            app.CustodianNode = uitreenode(app.Tree);
            app.CustodianNode.Text = 'Custodian';
            app.CorrespondingNode = uitreenode(app.Tree);
            app.CorrespondingNode.Text = 'Corresponding';
            % Assign Checked Nodes
            app.Tree.CheckedNodesChangedFcn = createCallbackFcn(app, @AuthorTreeCheckednodesChangedFcn, true);
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushedFcn, true);
            app.SaveButton.Text = 'Save';
            app.AdditionalDetailsTextAreaLabel = uilabel(app.UIFigure);
            app.AdditionalDetailsTextAreaLabel.Text = {'Additional'; 'Details'};
            app.AdditionalDetailsTextArea = uitextarea(app.UIFigure);
            app.AdditionalDetailsTextArea.ValueChangingFcn = createCallbackFcn(app, @AdditionalDetailsValueChangingFcn, true);
            app.AdditionalDetailsTextArea.Tooltip = {'Anything else you''d like us to know about this author? Note: This field is not public facing.'};
            app.AuthorEmailLabel = uilabel(app.UIFigure);
            app.AuthorEmailLabel.Text = 'Author Email';
            app.AuthorEmailEditField = uieditfield(app.UIFigure, 'text');
            app.AuthorEmailEditField.ValueChangingFcn = createCallbackFcn(app, @AuthorEmailValueChangingFcn, true);
            app.RelatedPublicationsTextAreaLabel = uilabel(app.UIFigure);
            app.RelatedPublicationsTextAreaLabel.Text = {'Related'; 'Publications'};
            app.RelatedPublicationsTextArea = uitextarea(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Publication'; 'DOI'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.RelatedPublicationLabel = uilabel(app.UIFigure);
            app.RelatedPublicationLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.UIFigure, 'text');
            app.DigitalIdentifierEditField.ValueChangingFcn = createCallbackFcn(app, @DigitalIdentifierValueChangingFcn, true);
            app.DigitalIdentifierEditField.Tooltip = {'ORC ID'};
            app.GivenNameEditField_2Label = uilabel(app.UIFigure);
            app.GivenNameEditField_2Label.Text = 'Given Name';
            app.GivenNameEditField_2 = uieditfield(app.UIFigure, 'text');
            app.GivenNameEditField_2.ValueChangingFcn = createCallbackFcn(app, @GivenNameValueChangingFcn, true);
            app.FamilyNameEditFieldLabel = uilabel(app.UIFigure);
            app.FamilyNameEditFieldLabel.Text = 'Family Name';
            app.FamilyNameEditField = uieditfield(app.UIFigure, 'text');
            app.FamilyNameEditField.ValueChangingFcn = createCallbackFcn(app, @FamilyNameValueChangingFcn, true);
            app.AuthorRoleLabel = uilabel(app.UIFigure);
            app.AuthorRoleLabel.Text = 'Author Role';
            app.InstitutesAffiliationsTextAreaLabel = uilabel(app.UIFigure);
            app.InstitutesAffiliationsTextAreaLabel.Text = 'Institutes/ Affiliations';
            app.InstitutesAffiliationsTextArea = uitextarea(app.UIFigure);
            app.InstitutesAffiliationsTextArea.ValueChangingFcn = createCallbackFcn(app, @InstitutesAffiliationsValueChangingFcn, true);
            app.NextButton = uibutton(app.UIFigure, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButtonPushedFcn, true);
            app.NextButton.Text = 'Next';
            app.AddButton = uibutton(app.UIFigure, 'push');
            app.AddButton.ButtonPushedFcn = createCallbackFcn(app, @AddButtonPushedFcn, true);
            app.AddButton.Text = 'Add';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app3v1_4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end