classdef LoginDialog < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LogintoNDICloudUIFigure  matlab.ui.Figure
        GridLayout               matlab.ui.container.GridLayout
        EnterEmailOptionInput    matlab.ui.control.DropDown
        ShowPasswordButton       matlab.ui.control.StateButton
        CreateAccountLink        matlab.ui.control.Hyperlink
        SignInButton             matlab.ui.control.Button
        NdiLogoImage             matlab.ui.control.Image
        EnterPasswordLabel       matlab.ui.control.Label
        EnterEmailLabel          matlab.ui.control.Label
        EnterPasswordInput       matlab.ui.control.EditField
    end

    properties (Access = private)
        Password = ''; % Password
        Asterisk = char(9679) % Character used to hide password
        EmailAddressList
    end

    methods
        function waitfor(app)
            uiwait(app.LogintoNDICloudUIFigure)
        end
    end
    
    methods (Access = private)
        
        function showLoginFailed(app)
            uialert( app.LogintoNDICloudUIFigure, ...
                "Login failed", ...
                "Login failed")
        end

        function showLoginSuccessful(app)
            progressbar = uiprogressdlg( app.LogintoNDICloudUIFigure, ...
                "Title", "Login Sucessful", ...
                "Message", "Logged in.");
            pause(1.5)
            delete(progressbar)
        end

        function hidePassword(app)
            numCharacters = numel(app.Password);
            app.EnterPasswordInput.Value = repmat(app.Asterisk, 1, numCharacters);
        end

        function loadEmailAdresses(app)
            filePath = app.getEmailAdressFilename();
            if ~isfile(filePath); return; end
            
            fileText = fileread(filePath);
            app.EmailAddressList = strsplit(fileText, newline);
            app.EnterEmailOptionInput.Items = [{''}, app.EmailAddressList];
        end

        function saveEmailAdresses(app)
            app.EmailAddressList = unique(app.EmailAddressList);
            fileText = strjoin(app.EmailAddressList, newline);
            
            filePath = app.getEmailAdressFilename();

            fid = fopen(filePath, 'w');
            fwrite(fid, fileText);
            fclose(fid);
        end

        function fileName = getEmailAdressFilename(app)

            folderPath = fullfile(userpath, 'Preferences', 'NDI');
            fileName = fullfile(folderPath, 'email_adress_history.txt');
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, userName)
            arguments
                app
                userName (1,1) string = missing
            end
            
            ndi.gui.utility.centerFigure(app.LogintoNDICloudUIFigure)
            app.loadEmailAdresses()

            if ~ismissing(userName)
                app.EnterEmailOptionInput.Value = userName;
            end
            
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
        end

        % Value changing function: EnterPasswordInput
        function EnterPasswordInputValueChanging(app, event)
            changingValue = event.Value;
            if isempty(changingValue); return; end
            
            isAsterisk = changingValue==app.Asterisk;
            if numel(isAsterisk) > numel(app.Password)
                app.Password(numel(isAsterisk))='*';
                app.Password(~isAsterisk) = changingValue(~isAsterisk);
            elseif numel(isAsterisk) < numel(app.Password)
                app.Password = app.Password(1:numel(isAsterisk));
                app.Password(~isAsterisk) = changingValue(~isAsterisk);
            else
                %pass
            end
            
            %disp(app.Password)
            if ~app.ShowPasswordButton.Value
                app.hidePassword()
            end
            drawnow
        end

        % Button pushed function: SignInButton
        function SignInButtonPushed(app, event)
            email = app.EnterEmailOptionInput.Value;
            password = app.Password;
            
            if ~any(strcmp(app.EmailAddressList, email))
                app.EmailAddressList{end+1} = email;
                app.saveEmailAdresses()
            end

            progressbar = uiprogressdlg( app.LogintoNDICloudUIFigure, ...
                "Title", "Logging in", ...
                "Message", "Please wait while logging in.", ...
                "Indeterminate", "on" );

            [b,answer,apiResponse,~] = ndi.cloud.api.auth.login(email, password);

            if b
                token = answer.token;
                organization_id = answer.user.organizations.id;
                setenv('NDI_CLOUD_TOKEN', token);
                setenv('NDI_CLOUD_ORGANIZATION_ID', organization_id)
                progressbar.Message = 'Successfully logged in!';
                progressbar.Indeterminate = 'off';
                progressbar.Icon = "Success";
                pause(1.5)
                uiresume(app.LogintoNDICloudUIFigure)
                delete(app)
            else
                delete(progressbar); % Close the progress bar
                
                body_details = jsonencode(answer, "PrettyPrint", true);
                error_message = sprintf('Login Failed.\nStatus: %d %s\nDetails: %s', ...
                    apiResponse.StatusCode, apiResponse.StatusLine.ReasonPhrase, body_details);
                uialert(app.LogintoNDICloudUIFigure, error_message, 'Login Error');
            end
        end

        % Value changed function: ShowPasswordButton
        function ShowPasswordButtonValueChanged(app, event)
            
            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));

            value = app.ShowPasswordButton.Value;
            if value
                app.EnterPasswordInput.Value = app.Password;
                app.ShowPasswordButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'hide.png');
            else
                app.hidePassword()
                app.ShowPasswordButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'show.png');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.LogintoNDICloudUIFigure = uifigure('Visible', 'off');
            app.LogintoNDICloudUIFigure.Name = 'Login to NDI Cloud';
            app.GridLayout = uigridlayout(app.LogintoNDICloudUIFigure);
            app.EnterPasswordInput = uieditfield(app.GridLayout, 'text');
            app.EnterPasswordInput.ValueChangingFcn = createCallbackFcn(app, @EnterPasswordInputValueChanging, true);
            app.EnterPasswordInput.Placeholder = 'Password';
            app.EnterEmailLabel = uilabel(app.GridLayout);
            app.EnterEmailLabel.Text = 'Enter Email';
            app.EnterPasswordLabel = uilabel(app.GridLayout);
            app.EnterPasswordLabel.Text = 'Enter Password';
            app.NdiLogoImage = uiimage(app.GridLayout);
            app.NdiLogoImage.ImageSource = fullfile(pathToMLAPP, '..', 'resources', 'images', 'ndi_logo.png');
            app.SignInButton = uibutton(app.GridLayout, 'push');
            app.SignInButton.ButtonPushedFcn = createCallbackFcn(app, @SignInButtonPushed, true);
            app.SignInButton.Text = 'Sign In';
            app.CreateAccountLink = uihyperlink(app.GridLayout);
            app.CreateAccountLink.URL = 'https://ndi-web-app-dev.vercel.app/createAccount';
            app.CreateAccountLink.Text = 'Create Account';
            app.ShowPasswordButton = uibutton(app.GridLayout, 'state');
            app.ShowPasswordButton.ValueChangedFcn = createCallbackFcn(app, @ShowPasswordButtonValueChanged, true);
            app.ShowPasswordButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'show.png');
            app.ShowPasswordButton.Text = '';
            app.EnterEmailOptionInput = uidropdown(app.GridLayout);
            app.EnterEmailOptionInput.Items = {'', 'eivind@walthamdatascience.com'};
            app.EnterEmailOptionInput.Editable = 'on';
            app.EnterEmailOptionInput.Placeholder = 'Email';
            app.EnterEmailOptionInput.Value = '';
            % Show the figure after all components are created
            app.LogintoNDICloudUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LoginDialog(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LogintoNDICloudUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LogintoNDICloudUIFigure)
        end
    end
end