classdef DatasetUploadMonitor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DatasetUploadUIFigure           matlab.ui.Figure
        NDILogoImage                    matlab.ui.control.Image
        UploadingDatasetLabel           matlab.ui.control.Label
        VLineDecorator_1                matlab.ui.container.Panel
        VLineDecorator_2                matlab.ui.container.Panel
        RetrieveDocumentsPanel          matlab.ui.container.Panel
        RetrieveDocumentsLabel          matlab.ui.control.Label
        RetrieveDocumentsProgressLabel  matlab.ui.control.Label
        RetreiveDocumentsProgressbarImage  matlab.ui.control.Image
        StepLabel_1                     matlab.ui.control.Label
        StepLamp_1                      matlab.ui.control.Lamp
        UploadDocumentsPanel            matlab.ui.container.Panel
        DocumentProgressPanel           matlab.ui.container.Panel
        UploadDocumentsLabel            matlab.ui.control.Label
        StepLabel_2                     matlab.ui.control.Label
        StepLamp_2                      matlab.ui.control.Lamp
        UploadFilesPanel                matlab.ui.container.Panel
        FileProgressPanel               matlab.ui.container.Panel
        UploadFilesLabel                matlab.ui.control.Label
        StepLabel_3                     matlab.ui.control.Label
        StepLamp                        matlab.ui.control.Lamp
    end

    
    properties (Access = private)
        DocumentProgressBar % Description
        FileProgressBar
        DocumentRetrievedListener
        MessageUpdatedListener
    end
    
    methods (Access = private)
        
        function onDocumentsRetrieved(app, src, evt)
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.RetreiveDocumentsProgressbarImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'images', 'progressbar_completed.png');
            %app.RetrieveDocumentsProgressLabel.Text = src.CompletedMessage;
            drawnow
        end

        function onMessageUpdated(app, src, evt)
            app.RetrieveDocumentsProgressLabel.Text = evt.Message;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, progress_trackers)

            ndi.gui.utility.centerFigure(app.DatasetUploadUIFigure)

            app.DocumentRetrievedListener = listener(progress_trackers(1), 'TaskCompleted', ...
                    @app.onDocumentsRetrieved);

            app.MessageUpdatedListener = listener(progress_trackers(1), 'MessageUpdated', ...
                    @app.onMessageUpdated);

            app.DocumentProgressBar = ndi.gui.component.NDIProgressBar(...
                'ProgressTracker', progress_trackers(2), ...
                'Parent', app.DocumentProgressPanel, ...
                'Size', [402, 12],...
                'DisplayRemainingTime', true, ...
                'Text', 'Pending...', ...
                'BorderWidth', 1);
            
            app.FileProgressBar = ndi.gui.component.NDIProgressBar(...
                'ProgressTracker', progress_trackers(3), ...
                'Parent', app.FileProgressPanel, ...
                'Size', [402, 12], ...
                'Text', 'Pending...', ...
                'BorderWidth', 1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.DatasetUploadUIFigure = uifigure('Visible', 'off');
            app.DatasetUploadUIFigure.Name = 'Dataset Upload';
            app.UploadFilesPanel = uipanel(app.DatasetUploadUIFigure);
            app.StepLamp = uilamp(app.UploadFilesPanel);
            app.StepLabel_3 = uilabel(app.UploadFilesPanel);
            app.StepLabel_3.Text = '3';
            app.UploadFilesLabel = uilabel(app.UploadFilesPanel);
            app.UploadFilesLabel.Text = 'Upload Files';
            app.FileProgressPanel = uipanel(app.UploadFilesPanel);
            app.UploadDocumentsPanel = uipanel(app.DatasetUploadUIFigure);
            app.StepLamp_2 = uilamp(app.UploadDocumentsPanel);
            app.StepLabel_2 = uilabel(app.UploadDocumentsPanel);
            app.StepLabel_2.Text = '2';
            app.UploadDocumentsLabel = uilabel(app.UploadDocumentsPanel);
            app.UploadDocumentsLabel.Text = 'Upload Documents';
            app.DocumentProgressPanel = uipanel(app.UploadDocumentsPanel);
            app.RetrieveDocumentsPanel = uipanel(app.DatasetUploadUIFigure);
            app.StepLamp_1 = uilamp(app.RetrieveDocumentsPanel);
            app.StepLabel_1 = uilabel(app.RetrieveDocumentsPanel);
            app.StepLabel_1.Text = '1';
            app.RetreiveDocumentsProgressbarImage = uiimage(app.RetrieveDocumentsPanel);
            app.RetreiveDocumentsProgressbarImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'images', 'indeterminate_progressbar.gif');
            app.RetrieveDocumentsProgressLabel = uilabel(app.RetrieveDocumentsPanel);
            app.RetrieveDocumentsProgressLabel.Text = 'Retrieving documents... This can take a couple of minutes.';
            app.RetrieveDocumentsLabel = uilabel(app.RetrieveDocumentsPanel);
            app.RetrieveDocumentsLabel.Text = 'Retrieve Documents';
            app.VLineDecorator_2 = uipanel(app.DatasetUploadUIFigure);
            app.VLineDecorator_1 = uipanel(app.DatasetUploadUIFigure);
            app.UploadingDatasetLabel = uilabel(app.DatasetUploadUIFigure);
            app.UploadingDatasetLabel.Text = 'Uploading Dataset';
            app.NDILogoImage = uiimage(app.DatasetUploadUIFigure);
            app.NDILogoImage.ImageSource = fullfile(pathToMLAPP, 'resources', 'images', 'ndi_logo.png');
            % Show the figure after all components are created
            app.DatasetUploadUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DatasetUploadMonitor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DatasetUploadUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DatasetUploadUIFigure)
        end
    end
end