classdef StrainForm < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        EnterStrainDetailsLabel         matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        FormGridLayout                  matlab.ui.container.GridLayout
        BackgroundStrainDropDownLabel   matlab.ui.control.Label
        DiseaseModelDropDown            matlab.ui.control.DropDown
        DiseaseModelDropDownLabel       matlab.ui.control.Label
        SynonymsEditFieldLabel          matlab.ui.control.Label
        OntologyIdentifierLabel         matlab.ui.control.Label
        DescriptionTextArea             matlab.ui.control.TextArea
        DescriptionTextAreaLabel        matlab.ui.control.Label
        StockNumberEditFieldLabel       matlab.ui.control.Label
        LaboratoryCodeEditFieldLabel    matlab.ui.control.Label
        PhenotypeEditFieldLabel         matlab.ui.control.Label
        BreedingTypeDropDownLabel       matlab.ui.control.Label
        AlternateIdentifierEditFieldLabel  matlab.ui.control.Label
        SynonymsEditField               matlab.ui.control.EditField
        OntologyIdentifierEditField     matlab.ui.control.EditField
        AlternateIdentifierEditField    matlab.ui.control.EditField
        SearcIdentifierButton           matlab.ui.control.Button
        DigitalIdentifierEditField      matlab.ui.control.EditField
        DigitalIdentifierEditFieldLabel  matlab.ui.control.Label
        GeneticStrainTypeDropDownLabel  matlab.ui.control.Label
        StockNumberEditField            matlab.ui.control.EditField
        LaboratoryCodeEditField         matlab.ui.control.EditField
        BackgroundStrainDropDown2       matlab.ui.control.DropDown
        BackgroundStrainDropDown1       matlab.ui.control.DropDown
        InstructionTextLabel            matlab.ui.control.Label
        BreedingTypeDropDown            matlab.ui.control.DropDown
        PhenotypeEditField              matlab.ui.control.EditField
        GeneticStrainTypeDropDown       matlab.ui.control.DropDown
        ChangeDiseaseTypeButton         matlab.ui.control.Button
        SpeciesDropDown                 matlab.ui.control.DropDown
        NameEditField                   matlab.ui.control.EditField
        SpeciesDropDownLabel            matlab.ui.control.Label
        FullNameLabel                   matlab.ui.control.Label
        GridLayout2                     matlab.ui.container.GridLayout
        CancelButton                    matlab.ui.control.Button
        SaveButton                      matlab.ui.control.Button
        ContextMenu                     matlab.ui.container.ContextMenu
        DiseaseMenu                     matlab.ui.container.Menu
        DiseaseModelMenu                matlab.ui.container.Menu
    end

    properties (Dependent)
        Visible (1,1) matlab.lang.OnOffSwitchState
    end

    properties
        PropertyNames (1,:) string
    end

    properties
        % Whether the user confirms (saves changes) or cancels
        FinishState (1,1) string % "Save" or "Cancel"
    end

    properties (Access = private)
        SpeciesInstances (:,1) struct
        StrainInstances (:,1) struct
        OriginalData (1,1) struct
        IsEditing (1,1) logical = false % Whether a previously created instance/struct is being edited.
        IsStandalone (1,1) logical = true % If app is opened independently or from another app (i.e NDI Dataset Uploader)
        UIForm (1,1) struct
    end

    methods 
        function set.Visible(app, value)
            app.UIFigure.Visible = value;
        end
        function value = get.Visible(app)
            value = app.UIFigure.Visible;
        end
    end
    
    methods (Access = public)

        function setFormData(app, S, editExisting)
            if nargin < 3; editExisting = true; end
            app.IsEditing = editExisting;

            components = app.FormGridLayout.Children;

            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button')
                    continue
                end

                propertyName = components(i).Tag;
                if isfield(S, propertyName)
                    if isempty(S.(propertyName)) || all(isstring(S.(propertyName)) & S.(propertyName)=="")
                        continue
                    end

                    % Only update if value is there. This is a bit of a
                    % mess, but not sure how to make it cleaner.
                    if strcmp(propertyName, 'backgroundStrain')
                        if components(i) == app.BackgroundStrainDropDown1
                            if S.(propertyName)(1) ~= ""
                                components(i).Value = S.(propertyName)(1);
                            end
                        elseif components(i) == app.BackgroundStrainDropDown2
                            if numel(S.(propertyName))==2
                                if S.(propertyName)(2) ~= ""
                                    components(i).Value = S.(propertyName)(2);
                                end
                            end
                        end
                    else
                        components(i).Value = S.(propertyName);
                    end
                end
            end

            app.OriginalData = app.getFormData();
        end

        function S = getFormData(app)
            S = struct;

            components = app.FormGridLayout.Children;
            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button')
                    continue
                elseif isempty(components(i).Tag)
                    continue
                else
                    propertyName = components(i).Tag;
                    propertyValue = components(i).Value;
                    
                    % Replace missing values with empty char
                    if ismissing(propertyValue); propertyValue = ''; end

                    if strcmp(propertyName, 'backgroundStrain')
                        if ~isfield(S, propertyName)
                            data = string.empty;
                        else
                            data = S.(propertyName);
                        end

                        if components(i) == app.BackgroundStrainDropDown1
                            data(1) = propertyValue;
                        elseif components(i) == app.BackgroundStrainDropDown2
                            data(2) = propertyValue;
                        end
                        S.(propertyName) = data;
                    else
                        propertyName = components(i).Tag;
                        S.(propertyName) = propertyValue;
                    end
                end
            end
            
            % Remove "blank" strings from background strain array
            S.backgroundStrain(S.backgroundStrain=="")=[];
        end

        function isValid = validateFormData(app, S)

            requiredFields = ["name", "species", "geneticStrainType"];
            hasRequiredFields = false(size(requiredFields));

            for i = 1:numel(requiredFields)
                if ~isempty( S.(requiredFields(i)) )
                    if ~ismissing( string( S.(requiredFields(i)) ) )
                        hasRequiredFields(i) = true;
                    end
                end
            end

            isValid = all(hasRequiredFields);
            
            if ~isValid
                uialert(app.UIFigure, 'Required fields are missing', 'Canceled')
            end
        end

        function reset(app)

            function resetField(component, ~)
                if isa(component,'matlab.ui.control.DropDown')
                    app.resetDropDownValue(component)
                elseif isa(component, 'matlab.ui.control.Button') || ...
                    isa(component, 'matlab.ui.control.StateButton')
                    % pass
                else
                    component.Value = "";
                end
            end

            app.doForInputFields(@resetField, [])
            app.FinishState = "";
        end

        function data = doForInputFields(app, functionHandle, data)
        % doForInputFields - Apply function handle to all input fields of form
            components = app.FormGridLayout.Children;

            for i = 1:numel(components)
                if isa(components(i), 'matlab.ui.control.Label') || ...
                        isa(components(i), 'matlab.ui.control.Button') || ...
                            isa(components(i), 'matlab.ui.control.StateButton')
                    continue
                else
                    if nargout == 1
                        data = functionHandle(components(i), data);
                    else
                        functionHandle(components(i), data);
                    end
                end
            end
            if ~nargout; clear data; end
        end

        function waitfor(app)
            app.IsStandalone = false;
            uiwait(app.UIFigure)
        end
    end

    methods (Access = private)
        function strainInstances = getStrainInstances(app)
            import ndi.database.metadata_app.fun.loadUserInstanceCatalog
            strainInstances = loadUserInstanceCatalog('Strain');
        end

        function speciesInstances = getSpeciesInstances(app)
            import ndi.database.metadata_app.fun.loadUserInstanceCatalog
            import ndi.database.metadata_app.fun.getOpenMindsInstances

            [names, options] = getOpenMindsInstances('Species');
            speciesInstances = loadUserInstanceCatalog('Species');

            % Todo: merge...
        end
        
        function S = openForm(app, formName, S, editExisting)
        %openForm Open form where user can enter details
            
            if nargin < 3; S = struct.empty; end
            if nargin < 4; editExisting = ~isempty(S); end

            progressDialog = uiprogressdlg(app.UIFigure, ...
                'Message', sprintf('Opening form for entering %s details', formName), ...
                'Title', 'Please wait...', 'Indeterminate', "on");
        
            if ~isfield(app.UIForm, formName) || ~isvalid(app.UIForm.(formName))
                appPackage = 'ndi.database.metadata_app.Apps';
                formAppName = sprintf('%s.%sForm', appPackage, formName);
                app.UIForm.(formName) = feval(formAppName); % Create the form
            else
                app.UIForm.(formName).Visible = 'on'; % Make the form visible
            end

            if nargin > 1 && ~isempty(S)
                % Initialize form if details exist and should be edited
                app.UIForm.(formName).setFormData(S, editExisting);
            end
                
            ndi.gui.utility.centerFigure(app.UIForm.(formName).UIFigure, ...
                 app.UIFigure, [20,-20])
            
            progressDialog.Message = sprintf('Enter %s details...', lower(formName));
            app.UIForm.(formName).waitfor(); % Wait for user to proceed

            % Get user-inputs from form
            S = app.UIForm.(formName).getFormData();
           
            % Update data in table if user pressed save.
            mode = app.UIForm.(formName).FinishState;
            if mode == "Save"
                % pass
            else
                S = struct.empty;
            end
            
            try
                app.UIForm.(formName).reset()
                app.UIForm.(formName).Visible = 'off'; % Hide the form (for later reuse
            catch
                delete(app.UIForm.(formName))
            end
            delete(progressDialog)
        end
    
        function resetDropDownValue(~, component)
            if component.Editable
                itemsData = component.ItemsData;
                component.ItemsData = [];
                component.Value = "";
                component.ItemsData = itemsData;
            else
                if ismissing(component.ItemsData(1))
                    % Cant set component.Value = missing directly, so reset
                    % and restore all the items instead.
                    itemsData = component.ItemsData;
                    items = component.Items;
                    component.ItemsData = [];
                    component.Items = "";
                    component.Items = items;
                    component.ItemsData = itemsData;
                else
                    component.Value = component.ItemsData(1);
                end
            end
        end

        function updateBackgrounStrainDropdown(app, strainCatalog)
            if strainCatalog.NumItems > 0
                items = string( {strainCatalog(:).name} );
            else
                items = {};
            end

            [items, itemsData] = ndi.database.metadata_app.fun.expandDropDownItems(items', items', 'Strain', "AddSelectOption", true, "AddCreateOption", true);
            app.BackgroundStrainDropDown1.Items = items;
            app.BackgroundStrainDropDown1.ItemsData = itemsData;
            app.BackgroundStrainDropDown2.Items = items;
            app.BackgroundStrainDropDown2.ItemsData = itemsData;
        end

        function addBackgroundStrain(app, event)
            speciesValue = app.SpeciesDropDown.Value;
            if ~isempty(speciesValue) && ~ismissing(speciesValue)
                sInit = struct('species', speciesValue);
            else
                sInit = struct.empty;
            end
            S = app.openForm("Strain", sInit, false); % false because we are not editing existing instance 
            if isempty(S)
                app.resetDropDownValue(event.Source)
                drawnow
            else
                strainInstances = getStrainInstances(app);
                try
                    strainInstances.add(S)
                    strainInstances.save()
                    app.updateBackgrounStrainDropdown(strainInstances)
                    event.Source.Value = S.name;
                catch ME
                    if ME.identifier == "Catalog:NamedItemExists"
                        uialert(app.UIFigure, sprintf("A strain with the name '%s' already exists", S.name), 'Strain Exists')
                    else
                        uialert(app.UIFigure, ME.message, 'Aborted')
                    end
                end
            end                        
        end

        function addSpecies(app, event)
            form = ndi.database.metadata_app.Apps.SpeciesForm();
            form.waitfor()

            if form.FinishState == "Save"
                S = form.getInfo;
                event.Source.ItemsData{end+1} = S.name;
                event.Source.Items{end+1} = S.name;
                event.Source.Value = S.name;
            else
                app.resetDropDownValue(event.Source)
                drawnow
            end
            delete(form)
        end

        function populateDropDownControl(~, uiDropDown, openMindsType)
        % populateDropDownControl - Populate a dropdown control with openminds instances

        % Note: This methods gathers all the instances for an openMINDS
        % type and allso prepends a set of actionable options the the
        % dropdown control given as input.

            import ndi.database.metadata_app.fun.loadOpenMindsInstanceCatalog
            import ndi.database.metadata_app.fun.expandDropDownItems
            
            assert(isa(uiDropDown, 'matlab.ui.control.DropDown'), ...
                'Input must be a DropDown control' )

            persistent typeMap
            if isempty(typeMap)
                keys = {'species', 'breedingType', 'geneticStrainType', 'diseaseModel'};
                values = {'Species', 'BreedingType', 'GeneticStrainType', 'DiseaseModel'};
                typeMap = containers.Map(keys, values);
            end

            if nargin < 3
                openMindsType = typeMap(uiDropDown.Tag);
            end
            
            % Load openminds instances and extract @ids and names
            catalog = loadOpenMindsInstanceCatalog(openMindsType);
            options = string( {catalog(:).at_id}' );
            names = string( {catalog(:).name}' );
            
            % Add some actionable options to the dropdown options
            if strcmpi(openMindsType, 'strain') || strcmp(openMindsType, 'species')
                [names, options] = expandDropDownItems(names, options, openMindsType, "AddSelectOption", true, "AddCreateOption", true);
            else
                [names, options] = expandDropDownItems(names, options, openMindsType, "AddSelectOption", true);
            end
  
            % Update dropdown control
            if uiDropDown.Editable
                uiDropDown.Placeholder = sprintf("Select a %s", openMindsType);
                uiDropDown.Value = "";
            end
            uiDropDown.Items = names;
            uiDropDown.ItemsData = options;
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            import ndi.database.metadata_app.fun.getOpenMindsInstances
            import ndi.database.metadata_app.fun.expandDropDownItems

            app.PropertyNames = properties(openminds.core.Strain);

            %Add tooltips for all the fields:
            mc = ?openminds.core.Strain;

            components = app.FormGridLayout.Children;
            for i = 1:numel(components)
                isMatch = strcmp({mc.PropertyList.Name}, components(i).Tag);
                if any(isMatch)
                    components(i).Tooltip = mc.PropertyList(isMatch).Description;
                end
            end

            keys = {'species', 'breedingType', 'geneticStrainType', 'diseaseModel'};
            values = {'Species', 'BreedingType', 'GeneticStrainType', 'DiseaseModel'};
            typeMap = containers.Map(keys, values);

            for i = 1:numel(components)
                if isKey(typeMap, components(i).Tag)
                    if isa(components(i), 'matlab.ui.control.DropDown')
                        app.populateDropDownControl( components(i) )                     
                    end
                end
            end
            
            strainInstances = getStrainInstances(app);
            app.updateBackgrounStrainDropdown(strainInstances)

            app.OriginalData = app.getFormData();
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            formData = app.getFormData();
            isValid = app.validateFormData(formData);
            if ~isValid; return; end
            
            if isequaln(formData, app.OriginalData)
                app.FinishState = "Cancel";
                app.UIFigureCloseRequest();
                return
            end
            
            strainInstances = getStrainInstances(app);
            if any( strainInstances.contains(app.NameEditField.Value) ) && ~app.IsEditing
                question = sprintf('A strain with the given name "%s" already exists. Do you want to abort or continue editing?', app.NameEditField.Value);
                options = {'Abort', 'Continue editing'};
                answer = uiconfirm(app.UIFigure, question, 'Strain Exists', 'Options', options);
                switch answer
                    case 'Abort'
                        app.FinishState = "Cancel";
                        app.UIFigureCloseRequest();
                    case 'Continue editing'
                        % pass
                end
            else
                app.FinishState = "Save";
                if app.IsStandalone
                    disp(app.getFormData)
                end
                app.UIFigureCloseRequest();
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.FinishState = "Cancel";
            app.UIFigureCloseRequest();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if app.IsStandalone
                delete(app)
            else
                formData = app.getFormData();
                
                if ~isequaln(formData, app.OriginalData)
                    if app.FinishState == ""
                        app.FinishState = uiconfirm(app.UIFigure, 'Do you want to save strain details?', 'Save changes?', 'Options', {'Save', 'Cancel'} );
                    end
                end
                uiresume(app.UIFigure)
            end
        end

        % Callback function
        function SearchFullNameButtonPushed(app, event)
            % todo
        end

        % Button pushed function: ChangeDiseaseTypeButton
        function ChangeDiseaseTypeButtonPushed(app, event)
            pos = getpixelposition(event.Source, true);
            app.ContextMenu.open(pos(1), pos(2))
        end

        % Value changed function: BackgroundStrainDropDown1, 
        % ...and 2 other components
        function dropdownValueChanged(app, event)
            value = event.Value;
            if ismissing(value)
                app.resetDropDownValue(event.Source)
            end

            if value == "create new"
                if event.Source.Editable
                    itemsData = event.Source.ItemsData;
                    items = event.Source.Items;
                    event.Source.ItemsData = [];
                    event.Source.Items = sprintf("creating new %s...", event.Source.Tag);
                    event.Source.Value = sprintf("creating new %s...", event.Source.Tag);
                    event.Source.ItemsData = itemsData;
                    event.Source.Items = items;
                    drawnow
                end

                switch event.Source.Tag
                    case 'backgroundStrain'
                        app.addBackgroundStrain(event)
                    case 'species'
                        app.addSpecies(event)
                end
            end
        end

        % Menu selected function: DiseaseMenu, DiseaseModelMenu
        function DiseaseMenuSelected(app, event)
            app.DiseaseMenu.Checked = 'off';
            app.DiseaseModelMenu.Checked = 'off';
            event.Source.Checked = 'on';
            app.populateDropDownControl(app.DiseaseModelDropDown, event.Source.Text)
        end

        % Value changed function: LaboratoryCodeEditField
        function LaboratoryCodeEditFieldValueChanged(app, event)
            value = app.LaboratoryCodeEditField.Value;
            
            % Check that the code is valid.
            s = openminds.core.Strain;
            try
                s.laboratoryCode = value;
            catch
                app.LaboratoryCodeEditField.Value = event.PreviousValue;
                msg = sprintf(app.LaboratoryCodeEditField.Tooltip);
                uialert(app.UIFigure, msg, 'Invalid Laboratory Code')
            end
        end

        % Value changed function: DigitalIdentifierEditField
        function DigitalIdentifierEditFieldValueChanged(app, event)
            value = app.DigitalIdentifierEditField.Value;
            
            % Check that the identifier is valid.
            s = openminds.core.Strain;
            try
                if isempty(value)
                    % pass
                elseif startsWith(value, "RRID")
                    hWaitbar = uiprogressdlg(app.UIFigure, "Indeterminate", "on", ...
                        "Message", "Please wait a moment while RRID is validated...", ...
                        "Title", "Checking RRID")

                    value = sprintf("https://scicrunch.org/resolver/%s", value);
                    s.digitalIdentifier = openminds.core.RRID('identifier', value);

                    try
                        res = webread(value);
                        hWaitbar.Indeterminate = "off";
                        hWaitbar.Message = "Valid RRID";
                        hWaitbar.Icon = "success";
                        pause(0.6)
                        delete(hWaitbar)

                    catch ME
                        delete(hWaitbar)
                        switch ME.identifier
                            case 'MATLAB:webservices:HTTP404StatusCodeError'
                                error('RRID was not found on the RRID Portal. Please make sure that you have a valid RRID.')
                            otherwise
                                rethrow(ME)
                        end
                    end
                else
                    error('Identifier must start with RRID')
                end
            catch ME
                app.DigitalIdentifierEditField.Value = event.PreviousValue;
                %msg = sprintf('%s\n\n%s', app.DigitalIdentifierEditField.Tooltip, ME.message);
                uialert(app.UIFigure, ME.message, 'Invalid Digital Identifier')
            end
        end

        % Button pushed function: SearcIdentifierButton
        function SearcIdentifierButtonPushed(app, event)
            % Get value of strain name:
            
            % Use this to search in scicrunch.
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Enter Strain Info';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.SaveButton = uibutton(app.GridLayout2, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CancelButton = uibutton(app.GridLayout2, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.Panel = uipanel(app.GridLayout);
            app.FormGridLayout = uigridlayout(app.Panel);
            app.FullNameLabel = uilabel(app.FormGridLayout);
            app.FullNameLabel.Tag = 'name';
            app.FullNameLabel.Text = 'Name';
            app.SpeciesDropDownLabel = uilabel(app.FormGridLayout);
            app.SpeciesDropDownLabel.Tag = 'species';
            app.SpeciesDropDownLabel.Text = 'Species';
            app.NameEditField = uieditfield(app.FormGridLayout, 'text');
            app.NameEditField.Tag = 'name';
            app.NameEditField.Tooltip = {'Enter the title of this publication.'};
            app.SpeciesDropDown = uidropdown(app.FormGridLayout);
            app.SpeciesDropDown.Editable = 'on';
            app.SpeciesDropDown.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.SpeciesDropDown.Tag = 'species';
            app.ChangeDiseaseTypeButton = uibutton(app.FormGridLayout, 'push');
            app.ChangeDiseaseTypeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeDiseaseTypeButtonPushed, true);
            app.ChangeDiseaseTypeButton.Tooltip = {'Add a new species'};
            app.ChangeDiseaseTypeButton.Text = 'o';
            app.GeneticStrainTypeDropDown = uidropdown(app.FormGridLayout);
            app.GeneticStrainTypeDropDown.Tag = 'geneticStrainType';
            app.PhenotypeEditField = uieditfield(app.FormGridLayout, 'text');
            app.PhenotypeEditField.Tag = 'phenotype';
            app.BreedingTypeDropDown = uidropdown(app.FormGridLayout);
            app.BreedingTypeDropDown.Tag = 'breedingType';
            app.BreedingTypeDropDown.Placeholder = 'select a Species';
            app.InstructionTextLabel = uilabel(app.FormGridLayout);
            app.InstructionTextLabel.Text = 'Description here';
            app.BackgroundStrainDropDown1 = uidropdown(app.FormGridLayout);
            app.BackgroundStrainDropDown1.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.BackgroundStrainDropDown1.Tag = 'backgroundStrain';
            app.BackgroundStrainDropDown2 = uidropdown(app.FormGridLayout);
            app.BackgroundStrainDropDown2.ValueChangedFcn = createCallbackFcn(app, @dropdownValueChanged, true);
            app.BackgroundStrainDropDown2.Tag = 'backgroundStrain';
            app.LaboratoryCodeEditField = uieditfield(app.FormGridLayout, 'text');
            app.LaboratoryCodeEditField.ValueChangedFcn = createCallbackFcn(app, @LaboratoryCodeEditFieldValueChanged, true);
            app.LaboratoryCodeEditField.Tag = 'laboratoryCode';
            app.StockNumberEditField = uieditfield(app.FormGridLayout, 'text');
            app.StockNumberEditField.Tag = 'stockNumber';
            app.GeneticStrainTypeDropDownLabel = uilabel(app.FormGridLayout);
            app.GeneticStrainTypeDropDownLabel.Tag = 'geneticStrainType';
            app.GeneticStrainTypeDropDownLabel.Text = 'Genetic Strain Type';
            app.DigitalIdentifierEditFieldLabel = uilabel(app.FormGridLayout);
            app.DigitalIdentifierEditFieldLabel.Tag = 'digitalIdentifier';
            app.DigitalIdentifierEditFieldLabel.Text = 'Digital Identifier';
            app.DigitalIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.DigitalIdentifierEditField.ValueChangedFcn = createCallbackFcn(app, @DigitalIdentifierEditFieldValueChanged, true);
            app.DigitalIdentifierEditField.Tag = 'digitalIdentifier';
            app.SearcIdentifierButton = uibutton(app.FormGridLayout, 'push');
            app.SearcIdentifierButton.ButtonPushedFcn = createCallbackFcn(app, @SearcIdentifierButtonPushed, true);
            app.SearcIdentifierButton.Icon = fullfile(pathToMLAPP, 'resources', 'icons', 'search.png');
            app.SearcIdentifierButton.Visible = 'off';
            app.SearcIdentifierButton.Tooltip = {'Search the SCICrunch RRID portal'};
            app.SearcIdentifierButton.Text = '';
            app.AlternateIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.AlternateIdentifierEditField.Tag = 'alternateIdentifier';
            app.OntologyIdentifierEditField = uieditfield(app.FormGridLayout, 'text');
            app.OntologyIdentifierEditField.Tag = 'ontologyIdentifier';
            app.SynonymsEditField = uieditfield(app.FormGridLayout, 'text');
            app.SynonymsEditField.Tag = 'synonym';
            app.AlternateIdentifierEditFieldLabel = uilabel(app.FormGridLayout);
            app.AlternateIdentifierEditFieldLabel.Tag = 'alternateIdentifier';
            app.AlternateIdentifierEditFieldLabel.Text = 'Alternate Identifier';
            app.BreedingTypeDropDownLabel = uilabel(app.FormGridLayout);
            app.BreedingTypeDropDownLabel.Tag = 'breedingType';
            app.BreedingTypeDropDownLabel.Text = 'Breeding Type';
            app.PhenotypeEditFieldLabel = uilabel(app.FormGridLayout);
            app.PhenotypeEditFieldLabel.Tag = 'phenotype';
            app.PhenotypeEditFieldLabel.Text = 'Phenotype';
            app.LaboratoryCodeEditFieldLabel = uilabel(app.FormGridLayout);
            app.LaboratoryCodeEditFieldLabel.Tag = 'laboratoryCode';
            app.LaboratoryCodeEditFieldLabel.Text = 'Laboratory Code';
            app.StockNumberEditFieldLabel = uilabel(app.FormGridLayout);
            app.StockNumberEditFieldLabel.Tag = 'stockNumber';
            app.StockNumberEditFieldLabel.Text = 'Stock Number';
            app.DescriptionTextAreaLabel = uilabel(app.FormGridLayout);
            app.DescriptionTextAreaLabel.Tag = 'description';
            app.DescriptionTextAreaLabel.Text = 'Description';
            app.DescriptionTextArea = uitextarea(app.FormGridLayout);
            app.DescriptionTextArea.Tag = 'description';
            app.OntologyIdentifierLabel = uilabel(app.FormGridLayout);
            app.OntologyIdentifierLabel.Tag = 'ontologyIdentifier';
            app.OntologyIdentifierLabel.Text = 'Ontology Identifier';
            app.SynonymsEditFieldLabel = uilabel(app.FormGridLayout);
            app.SynonymsEditFieldLabel.Tag = 'synonym';
            app.SynonymsEditFieldLabel.Text = 'Synonyms';
            app.DiseaseModelDropDownLabel = uilabel(app.FormGridLayout);
            app.DiseaseModelDropDownLabel.Tag = 'diseaseModel';
            app.DiseaseModelDropDownLabel.Text = 'Disease Model';
            app.DiseaseModelDropDown = uidropdown(app.FormGridLayout);
            app.DiseaseModelDropDown.Tag = 'diseaseModel';
            app.BackgroundStrainDropDownLabel = uilabel(app.FormGridLayout);
            app.BackgroundStrainDropDownLabel.Text = 'Background Strain';
            app.EnterStrainDetailsLabel = uilabel(app.GridLayout);
            app.EnterStrainDetailsLabel.Text = 'Enter Strain Details';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.DiseaseMenu = uimenu(app.ContextMenu);
            app.DiseaseMenu.MenuSelectedFcn = createCallbackFcn(app, @DiseaseMenuSelected, true);
            app.DiseaseMenu.Text = 'Disease';
            app.DiseaseModelMenu = uimenu(app.ContextMenu);
            app.DiseaseModelMenu.MenuSelectedFcn = createCallbackFcn(app, @DiseaseMenuSelected, true);
            app.DiseaseModelMenu.Checked = 'on';
            app.DiseaseModelMenu.Text = 'DiseaseModel';
            % Assign app.ContextMenu
            app.EnterStrainDetailsLabel.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StrainForm

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end